classdef PostProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroSpikeXPostProcessingUIFigure  matlab.ui.Figure
        MainTabGroup                 matlab.ui.container.TabGroup
        VisualizeTab                 matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        InactiveCellsDropDown        matlab.ui.control.DropDown
        InactiveCellDropDownLabel    matlab.ui.control.Label
        ActiveCellsDropDown          matlab.ui.control.DropDown
        ActiveCellDropDownLabel      matlab.ui.control.Label
        DecayFitsPanel               matlab.ui.container.Panel
        DecayFitsGridLayout          matlab.ui.container.GridLayout
        TimepointDropDownVisualize   matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        TimepointLabelVisualize      matlab.ui.control.Label
        RootFolderLabelVisualize     matlab.ui.control.Label
        LoadDataButtonVisualize      matlab.ui.control.Button
        MainSignalAxes               matlab.ui.control.UIAxes
        AccuracyTab                  matlab.ui.container.Tab
        accuracyGrid                 matlab.ui.container.GridLayout
        IsNoiseButton                matlab.ui.control.Button
        IsSignalButton               matlab.ui.control.Button
        TransientCountResultsTable   matlab.ui.control.Table
        TransientCountMetricsLabel   matlab.ui.control.Label
        AccuracyResultsTable         matlab.ui.control.Table
        AccuracyMetricsLabel         matlab.ui.control.Label
        SubmitTransientCountButton   matlab.ui.control.Button
        TransientCountEditField      matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        TransientCountLabel          matlab.ui.control.Label
        ValidationProgressLabel      matlab.ui.control.Label
        StartValidationButton        matlab.ui.control.Button
        RecommendedPercentageLabel   matlab.ui.control.Label
        PercentageEditField          matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        PercentageLabel              matlab.ui.control.Label
        ValidationModeDropDown       matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        ValidationModeLabel          matlab.ui.control.Label
        RootFolderLabelAccuracy      matlab.ui.control.Label
        LoadDataButtonAccuracy       matlab.ui.control.Button
        TransientErrorHistogramAxes  matlab.ui.control.UIAxes
        ValidationSignalAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RootFolderPath char 
        TimepointNames cell % Names of the identified timepoint folders
        
        % Each element of this struct array will hold data for one timepoint
        LoadedTimepointData struct % Fields: .name, .fdata, .signal_info, .smooth_signal, .calcium_spikes_output, .decay_rates_output
                                 % .calcium_spikes_output will store the direct output of findCalciumSpikes
                                 % We might also store quantifyNetwork... output if needed.
        
        AnalysisSettings struct % Loaded from preprocessing_settings.mat (contains prominence, timepointOrderInApp, duration, n)

        % --- Visualize Tab State ---
        CurrentTimepointIndexVisualize double
        CurrentCellNumberVisualize     double % Cell number selected (1-based for current TP's fdata)
        
        % --- Accuracy Tab State ---
        CurrentValidationCellIndex double
        CellsForValidation cell 
        TotalCellsForValidation double
        AccuracyMetricsResults struct 
        CurrentValidationMode char = 'cell'
        HistogramDifferences % store the vector of all differences from transient count accuracy check
    end

    % Constants
    properties (Constant, Access = private)
        SETTINGS_FILENAME           char = 'preprocessing_settings.mat';
        SIGNAL_INFO_FILENAME        char = 'signal_info_struct.mat';
        SMOOTH_SIGNAL_FILENAME      char = 'smooth_signal_matrix.mat';
        CALCIUM_SPIKES_FILENAME   char = 'calcium_spikes_raw_output.mat'; % findCalciumSpikes output
        DECAY_RATES_FILENAME        char = 'decay_rates_data.mat';
    end
    
    methods (Access = private)
        
        function disableVisualizeControls(app, disable)
            if disable
                state = 'off';
            else
                state = 'on';
            end
            app.TimepointDropDownVisualize.Enable = state;
            app.InactiveCellsDropDown.Enable = state;
            app.ActiveCellsDropDown.Enable = state;
        end

        function disableAccuracyControls(app, disable)
            % This function disables most controls on the accuracy tab.
            % Specific buttons like Start, IsSignal, IsNoise, SubmitCount are
            % managed by other logic (e.g., after data load or during validation).
            if disable
                state = 'off';
            else
                state = 'on';
            end
            app.ValidationModeDropDown.Enable = state;
            app.PercentageEditField.Enable = state;
            % Don't enable StartValidationButton here unconditionally; loadDataForApp will handle it.
            % If called with disable=true, ensure Start button is also off.
            if disable
                app.StartValidationButton.Enable = 'off';
            end
            app.IsSignalButton.Enable = 'off';
            app.IsNoiseButton.Enable = 'off';
            app.TransientCountEditField.Enable = 'off';
            app.SubmitTransientCountButton.Enable = 'off';
        end
        
        function updateAccuracyUIForMode(app)
            currentMode = app.ValidationModeDropDown.Value; % Use the dropdown's value directly
        
            if strcmp(currentMode, 'cell')
                % --- Switch TO Cell Accuracy Mode ---
                
                % 1. Show Cell Accuracy components
                app.AccuracyResultsTable.Visible = 'on';
                app.AccuracyMetricsLabel.Visible = 'on';
        
                % 2. Hide Transient Count components
                app.TransientCountResultsTable.Visible = 'off';
                app.TransientCountMetricsLabel.Visible = 'off';
                app.TransientCountEditField.Visible = 'off'; % Hide this
                app.TransientCountLabel.Visible = 'off'; % And its label
                app.SubmitTransientCountButton.Visible = 'off'; % And the button
                        
                % 3. CLEAR the histogram axes completely
                cla(app.TransientErrorHistogramAxes, 'reset');
                app.TransientErrorHistogramAxes.Visible = 'off'; % Also hide the now-empty axes
        
            elseif strcmp(currentMode, 'transient')
                % --- Switch TO Transient Count Mode ---
                
                % 1. Show Transient Count components
                app.TransientCountResultsTable.Visible = 'on';
                app.TransientCountMetricsLabel.Visible = 'on';
                app.TransientCountEditField.Visible = 'on'; % Show this
                app.TransientCountLabel.Visible = 'on'; % And its label
                app.SubmitTransientCountButton.Visible = 'on'; % And the button
                app.TransientErrorHistogramAxes.Visible = 'on';

                % 2. Hide Cell Accuracy components
                app.AccuracyResultsTable.Visible = 'off';
                app.AccuracyMetricsLabel.Visible = 'off';
                
                % Check if there is data to draw
                if ~isempty(app.HistogramDifferences)
                    % Redraw the histogram from the stored data property
                    histogram(app.TransientErrorHistogramAxes, app.HistogramDifferences, 'BinMethod', 'integer');
                    title(app.TransientErrorHistogramAxes, 'Overall Error Distribution');
                    xlabel(app.TransientErrorHistogramAxes, 'Error (Code - Manual)');
                    ylabel(app.TransientErrorHistogramAxes, '# of Cells');
                    grid(app.TransientErrorHistogramAxes, 'on');
                    hold(app.TransientErrorHistogramAxes, 'on');
                    xline(app.TransientErrorHistogramAxes, 0, 'r--', 'LineWidth', 2);
                    hold(app.TransientErrorHistogramAxes, 'off');
                else
                    % If no data exists yet, just clear it to be safe
                    cla(app.TransientErrorHistogramAxes, 'reset');
                end
        
            else
                % Fallback: Hide everything if mode is invalid
                app.AccuracyResultsTable.Visible = 'off';
                app.AccuracyMetricsLabel.Visible = 'off';
                app.TransientCountResultsTable.Visible = 'off';
                app.TransientCountMetricsLabel.Visible = 'off';
                cla(app.TransientErrorHistogramAxes, 'reset');
                app.TransientErrorHistogramAxes.Visible = 'off';
            end
        end
        
        function resetValidationState(app)
            app.CellsForValidation = {};
            app.CurrentValidationCellIndex = 0; 
            app.TotalCellsForValidation = 0;
            app.ValidationProgressLabel.Text = 'Status: Idle. Select mode and click Start.';
            cla(app.ValidationSignalAxes); title(app.ValidationSignalAxes, 'Cell for Review');
            
            % Only enable Start button if data is actually loaded
            if ~isempty(app.LoadedTimepointData) && ~isempty(app.LoadedTimepointData(1).name) % Check if first timepoint has a name (proxy for loaded)
                 app.StartValidationButton.Enable = 'on';
            else
                 app.StartValidationButton.Enable = 'off';
            end
            
            app.IsSignalButton.Enable = 'off';
            app.IsNoiseButton.Enable = 'off';
            app.SubmitTransientCountButton.Enable = 'off';
            app.TransientCountEditField.Enable = 'off';
            app.TransientCountEditField.Value = 0; 
            app.AccuracyResultsTable.Data = [];
            app.TransientCountResultsTable.Data = [];
        end
        
        function data_content = loadFile(app, filePath, expectedVarName)
            data_content = []; % Initialize to empty (failure/not found state)
            
            if ~isfile(filePath)
                fprintf('File not found during load: %s\n', filePath);
                return;
            end

            try
                % load() creates a struct where field names are the variables in the .mat file
                temp_loaded_struct_from_mat_file = load(filePath); 
            catch ME
                uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('Error during load operation for file %s:\n%s', filePath, ME.message), 'File Load Error');
                return;
            end

            if isfield(temp_loaded_struct_from_mat_file, expectedVarName)
                % The .mat file contained a variable whose name MATCHES expectedVarName.
                % We want the CONTENT of this variable.
                data_content = temp_loaded_struct_from_mat_file.(expectedVarName);
                % fprintf('Successfully loaded variable "%s" from %s.\n', expectedVarName, filePath);
            elseif length(fieldnames(temp_loaded_struct_from_mat_file)) == 1
                 % The .mat file had only ONE variable, and its name DIDN'T match expectedVarName.
                 % This is a fallback: assume the user wants the content of that single variable.
                 all_field_names = fieldnames(temp_loaded_struct_from_mat_file);
                 actual_var_name_in_file = all_field_names{1};
                 data_content = temp_loaded_struct_from_mat_file.(actual_var_name_in_file);
                 fprintf('Loaded single variable "%s" (was expecting "%s") from %s.\n', actual_var_name_in_file, expectedVarName, filePath);
            else
                % ExpectedVarName not found AND (there were multiple other variables OR zero variables).
                if ~isempty(fieldnames(temp_loaded_struct_from_mat_file))
                    uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('Expected variable "%s" not found in %s. Available variables: %s.', ...
                        expectedVarName, filePath, strjoin(fieldnames(temp_loaded_struct_from_mat_file),', ')), 'File Content Error');
                else 
                    uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('MAT file %s appears to be empty or has no loadable variables.', filePath), 'File Content Error');
                end
                % data_to_return remains empty
            end
        end
        
        function success = loadDataForApp(app)
            success = false;
            % Clear previous data
            app.TimepointNames = {};
            app.LoadedTimepointData = struct('name', {}, 'fdata', {}, ...
                                             'signal_info', {}, 'smooth_signal', {}, ...
                                             'calcium_spikes_output', {}, 'decay_rates_output', {});
            app.AnalysisSettings = struct();
            app.CurrentTimepointIndexVisualize = [];
            app.CurrentCellNumberVisualize = [];

            % Reset dropdowns
            app.TimepointDropDownVisualize.Items = {'Load Data First'};
            app.TimepointDropDownVisualize.Value = app.TimepointDropDownVisualize.Items{1};
            app.ActiveCellsDropDown.Items = {'-'}; app.ActiveCellsDropDown.ItemsData = []; app.ActiveCellsDropDown.Value = app.ActiveCellsDropDown.Items{1};
            app.InactiveCellsDropDown.Items = {'-'}; app.InactiveCellsDropDown.ItemsData = []; app.InactiveCellsDropDown.Value = app.InactiveCellsDropDown.Items{1};

            newRootFolder = uigetdir('', 'Select Root Sample Folder');
            if newRootFolder == 0
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'No folder selected.', 'Load Cancelled');
                return;
            end
            app.RootFolderPath = newRootFolder;
            app.RootFolderLabelVisualize.Text = ['Root: ' app.RootFolderPath];
            app.RootFolderLabelAccuracy.Text = ['Root: ' app.RootFolderPath];

            % 1. Load Preprocessing Settings from ROOT folder
            settingsFilePath = fullfile(app.RootFolderPath, app.SETTINGS_FILENAME);
            if isfile(settingsFilePath)
                try
                    app.AnalysisSettings = load(settingsFilePath);
                    fprintf('Loaded analysis settings from %s\n', settingsFilePath);
                catch ME
                    uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('Error loading %s: %s', app.SETTINGS_FILENAME, ME.message), 'Settings Load Error');
                end
            else
                uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('%s not found in root. Using defaults.', app.SETTINGS_FILENAME), 'Settings File Missing');
            end
            % Apply defaults if settings fields are missing
            if ~isfield(app.AnalysisSettings, 'ProminenceValue') || isempty(app.AnalysisSettings.ProminenceValue)
                app.AnalysisSettings.ProminenceValue = 1.5; 
            end
            if ~isfield(app.AnalysisSettings, 'analysisDurationSeconds') || isempty(app.AnalysisSettings.analysisDurationSeconds)
                app.AnalysisSettings.analysisDurationSeconds = 120;
            end
            if ~isfield(app.AnalysisSettings, 'n') || isempty(app.AnalysisSettings.n)
                app.AnalysisSettings.n = 60;
            end
            fprintf('Using Prominence: %.2f, Duration: %d s, Smooth n: %d\n', ...
                app.AnalysisSettings.ProminenceValue, app.AnalysisSettings.analysisDurationSeconds, app.AnalysisSettings.n);

            % 2. Identify Timepoint Folders (those containing fdata.mat)
            potentialTimepointBaseFolders = {};
            if isfield(app.AnalysisSettings, 'timepointOrderInApp') && ~isempty(app.AnalysisSettings.timepointOrderInApp)
                potentialTimepointBaseFolders = app.AnalysisSettings.timepointOrderInApp;
                fprintf('Using timepoint order from settings: %s\n', strjoin(potentialTimepointBaseFolders,', '));
            else
                d = dir(app.RootFolderPath);
                isub = [d(:).isdir]; 
                nameFolds = {d(isub).name}';
                nameFolds(ismember(nameFolds,{'.','..'})) = [];
                potentialTimepointBaseFolders = nameFolds;
                fprintf('No timepoint order in settings. Scanning all subfolders in root for fdata.mat.\n');
            end

            validTimepointsFound = 0;
            for i = 1:numel(potentialTimepointBaseFolders)
                tpFolderName = potentialTimepointBaseFolders{i};
                tpFolderFullPath = fullfile(app.RootFolderPath, tpFolderName);
                fdataFullPath = fullfile(tpFolderFullPath, 'fdata.mat');

                if isfolder(tpFolderFullPath) && isfile(fdataFullPath)
                    validTimepointsFound = validTimepointsFound + 1;
                    currentTPIdx = validTimepointsFound;

                    app.LoadedTimepointData(currentTPIdx).name = tpFolderName;
                    fprintf('Processing timepoint folder: %s\n', tpFolderName);

                    % Load fdata.mat
                    try
                        fdataStruct = load(fdataFullPath);
                        fieldNs = fieldnames(fdataStruct);
                        if ~isempty(fieldNs)
                            app.LoadedTimepointData(currentTPIdx).fdata = fdataStruct.(fieldNs{1});
                        else 
                            error('fdata.mat is empty.');
                        end
                    catch ME_fdata
                        uialert(app.NeuroSpikeXPostProcessingUIFigure, sprintf('Error loading fdata.mat from %s: %s', tpFolderName, ME_fdata.message), 'FData Load Error');
                        app.LoadedTimepointData(currentTPIdx).name = [tpFolderName ' (fdata error)'];
                        continue;
                    end

                    % Load other files from this timepoint’s subfolder
                    si_content = loadFile(app, fullfile(tpFolderFullPath, app.SIGNAL_INFO_FILENAME), 'signal_info_struct');
                    if ~isempty(si_content)
                        app.LoadedTimepointData(currentTPIdx).signal_info = si_content;
                    else
                        app.LoadedTimepointData(currentTPIdx).signal_info = struct();
                    end

                    sm_content = loadFile(app, fullfile(tpFolderFullPath, app.SMOOTH_SIGNAL_FILENAME), 'smooth_signal_matrix');
                    if ~isempty(sm_content)
                        app.LoadedTimepointData(currentTPIdx).smooth_signal = sm_content;
                    else
                        app.LoadedTimepointData(currentTPIdx).smooth_signal = [];
                    end

                    % <<< CHANGE HERE: specify the expected variable name EXACTLY >>>
                    cs_content = loadFile(app, ...
                              fullfile(tpFolderFullPath, app.CALCIUM_SPIKES_FILENAME), ...
                              'calcium_spikes_output_for_saving');
                    if ~isempty(cs_content)
                        app.LoadedTimepointData(currentTPIdx).calcium_spikes_output = cs_content;
                    else
                        app.LoadedTimepointData(currentTPIdx).calcium_spikes_output = struct();
                    end

                    dr_content = loadFile(app, fullfile(tpFolderFullPath, app.DECAY_RATES_FILENAME), 'decay_rates_data');
                    if ~isempty(dr_content)
                        app.LoadedTimepointData(currentTPIdx).decay_rates_output = dr_content;
                    else
                        app.LoadedTimepointData(currentTPIdx).decay_rates_output = struct();
                    end
                else
                    fprintf('Skipping folder (no fdata.mat or not a folder): %s\n', tpFolderName);
                end
            end

            app.TimepointNames = {app.LoadedTimepointData.name};

            if isempty(app.LoadedTimepointData) || validTimepointsFound == 0
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'No valid timepoint folders (containing fdata.mat and other necessary files) were successfully processed.', 'Major Load Error');
                return;
            end

            % Populate Timepoint DropDown
            app.TimepointDropDownVisualize.Items = app.TimepointNames;
            app.TimepointDropDownVisualize.Value = app.TimepointNames{1};
            app.CurrentTimepointIndexVisualize = 1;

            updateActiveInactiveCellDropDownVisualize(app);

            disableVisualizeControls(app, false);
            disableAccuracyControls(app, false);
            app.StartValidationButton.Enable = 'on';
            resetValidationState(app);
            success = true;

            % --- START: Automatic Loading of Saved Accuracy Metrics ---
            disp('Checking for previously saved accuracy metrics...');
            
            % --- 1. Load Transient Count Metrics ---
            transientMetricsFile = fullfile(app.RootFolderPath, 'PostProcessApp_TransientCountMetrics.mat');
            if isfile(transientMetricsFile)
                try
                    fprintf('Found previous transient count metrics. Loading...\n');
                    data_T = load(transientMetricsFile);
                    
                    if isfield(data_T, 'summaryData_ForDisplay') && isfield(data_T, 'summaryHeaders')
                        app.TransientCountResultsTable.ColumnName = data_T.summaryHeaders;
                        app.TransientCountResultsTable.Data = data_T.summaryData_ForDisplay;
                        
                        numCols_T = numel(data_T.summaryHeaders);
                        widths_T = repmat({'1x'}, 1, numCols_T);
                        widths_T{2} = 0; widths_T{5} = 0; widths_T{7} = 0; widths_T{8} = 0; widths_T{9} = 0;
                        app.TransientCountResultsTable.ColumnWidth = widths_T;

                        if isfield(data_T, 'all_differences')
                            try
                                app.HistogramDifferences = data_T.all_differences;
                                if ~isempty(app.HistogramDifferences)
                                    histogram(app.TransientErrorHistogramAxes, app.HistogramDifferences, 'BinMethod', 'integer');
                                    title(app.TransientErrorHistogramAxes, 'Overall Error Distribution (Loaded)');
                                    xlabel(app.TransientErrorHistogramAxes, 'Error (Code - Manual)');
                                    ylabel(app.TransientErrorHistogramAxes, '# of Cells');
                                    grid(app.TransientErrorHistogramAxes, 'on');
                                    hold(app.TransientErrorHistogramAxes, 'on');
                                    xline(app.TransientErrorHistogramAxes, 0, 'r--', 'LineWidth', 2);
                                    hold(app.TransientErrorHistogramAxes, 'off');
                                    fprintf('Successfully populated Transient Count histogram.\n');
                                end
                            catch ME_hist
                                warning('Could not repopulate transient histogram: %s', ME_hist.message);
                            end
                        end
            
                        fprintf('Successfully populated Transient Count metrics table.\n');
                    else
                        warning('Transient metrics file was found but is missing expected data variables.');
                    end
                catch ME
                    warning('Could not load or apply saved transient metrics. File may be corrupt. Error: %s', ME.message);
                end
            else
                fprintf('No previous transient count metrics file found.\n');
            end
            
            
            % --- 2. Load Cell Accuracy Metrics ---
            cellMetricsFile = fullfile(app.RootFolderPath, 'PostProcessApp_CellAccuracyMetrics.mat');
            if isfile(cellMetricsFile)
                try
                    fprintf('Found previous cell accuracy metrics. Loading...\n');
                    data_C = load(cellMetricsFile);
            
                    % **CORRECTED**: We now look for 'header' and 'rows' which we are saving.
                    if isfield(data_C, 'header') && isfield(data_C, 'rows')
                        
                        % Populate the UI table with the loaded data
                        app.AccuracyResultsTable.ColumnName = data_C.header;
                        app.AccuracyResultsTable.Data = data_C.rows;
                        
                        fprintf('Successfully populated Cell Accuracy metrics table.\n');
                    else
                        warning('Cell accuracy file was found but is missing expected data variables.');
                    end
                catch ME
                    warning('Could not load or apply saved cell accuracy metrics. File may be corrupt. Error: %s', ME.message);
                end
            else
                fprintf('No previous cell accuracy metrics file found.\n');
            end
            
            % --- END: Automatic Loading ---
        end
        
        function updateActiveInactiveCellDropDownVisualize(app)
            % Clear the main signal axes
            cla(app.MainSignalAxes);
            title(app.MainSignalAxes, '');
    
            % Safely delete any existing decay‐fits children
            if ~isempty(app.DecayFitsGridLayout) && isvalid(app.DecayFitsGridLayout)
                children = app.DecayFitsGridLayout.Children;
                % Keep only the valid handles
                validChildren = children(arrayfun(@(h) isvalid(h), children));
                delete(validChildren);
            end
            app.CurrentCellNumberVisualize = [];
    
            % Reset both dropdowns to “no data”
            noDataItems = {'-No Data-'};
            noDataValue = noDataItems{1};
            app.ActiveCellsDropDown.Items     = noDataItems;
            app.ActiveCellsDropDown.ItemsData = [];
            app.ActiveCellsDropDown.Value     = noDataValue;
    
            app.InactiveCellsDropDown.Items     = noDataItems;
            app.InactiveCellsDropDown.ItemsData = [];
            app.InactiveCellsDropDown.Value     = noDataValue;
    
            % If no timepoint has been selected or data isn’t loaded, bail
            if isempty(app.CurrentTimepointIndexVisualize) || ...
               app.CurrentTimepointIndexVisualize > numel(app.LoadedTimepointData) || ...
               isempty(app.LoadedTimepointData(app.CurrentTimepointIndexVisualize).name) || ...
               isempty(app.LoadedTimepointData(app.CurrentTimepointIndexVisualize).fdata)
                return;
            end
    
            % Grab the data for the selected timepoint
            tpData = app.LoadedTimepointData(app.CurrentTimepointIndexVisualize);
            fdata  = tpData.fdata;
            csOut  = tpData.calcium_spikes_output;
    
            % Determine how many cells (rows in fdata)
            nCells = size(fdata, 1);
    
            if nCells < 1
                % No cells to show
                app.ActiveCellsDropDown.Items   = {'No Cells'};
                app.InactiveCellsDropDown.Items = {'No Cells'};
                return;
            end
    
            % Extract the active/inactive lists from the calcium_spikes_output struct
            allIdx = (1:nCells)';
            actIdx = [];
            inactIdx = [];
            if isstruct(csOut) && isfield(csOut, 'active_cells') && ~isempty(csOut.active_cells)
                actIdx = csOut.active_cells(:,1);
                % Clip to valid range
                actIdx = actIdx(actIdx >= 1 & actIdx <= nCells);
            end
            if isstruct(csOut) && isfield(csOut, 'inactive_cells') && ~isempty(csOut.inactive_cells)
                inactIdx = csOut.inactive_cells(:,1);
                inactIdx = inactIdx(inactIdx >= 1 & inactIdx <= nCells);
            end
    
            % If one list is empty but the other isn't, derive the missing one
            if isempty(actIdx) && ~isempty(inactIdx)
                actIdx = setdiff(allIdx, inactIdx);
            elseif isempty(inactIdx) && ~isempty(actIdx)
                inactIdx = setdiff(allIdx, actIdx);
            elseif isempty(actIdx) && isempty(inactIdx)
                % Neither field existed or both empty: assume all are inactive
                inactIdx = allIdx;
            end
    
            % Populate the Active dropdown
            if ~isempty(actIdx)
                items = arrayfun(@num2str, sort(actIdx), 'UniformOutput', false);
                app.ActiveCellsDropDown.Items     = items;
                app.ActiveCellsDropDown.ItemsData = sort(actIdx);
                app.ActiveCellsDropDown.Value     = app.ActiveCellsDropDown.ItemsData(1);
            else
                app.ActiveCellsDropDown.Items     = {'None'};
                app.ActiveCellsDropDown.ItemsData = [];
                app.ActiveCellsDropDown.Value     = 'None';
            end
    
            % Populate the Inactive dropdown
            if ~isempty(inactIdx)
                items = arrayfun(@num2str, sort(inactIdx), 'UniformOutput', false);
                app.InactiveCellsDropDown.Items     = items;
                app.InactiveCellsDropDown.ItemsData = sort(inactIdx);
                app.InactiveCellsDropDown.Value     = app.InactiveCellsDropDown.ItemsData(1);
            else
                app.InactiveCellsDropDown.Items     = {'None'};
                app.InactiveCellsDropDown.ItemsData = [];
                app.InactiveCellsDropDown.Value     = 'None';
            end
    
            % Trigger the initial plot for whichever is first
            if ~isempty(app.ActiveCellsDropDown.ItemsData)
                app.CurrentCellNumberVisualize = app.ActiveCellsDropDown.ItemsData(1);
            elseif ~isempty(app.InactiveCellsDropDown.ItemsData)
                app.CurrentCellNumberVisualize = app.InactiveCellsDropDown.ItemsData(1);
            else
                return;
            end
            plotIndividualCellAnalysis(app);
        end
    
        function plotIndividualCellAnalysis(app)
            % ================================
            % 1) INITIAL SAFETY CHECKS
            % ================================
            if isempty(app.CurrentTimepointIndexVisualize) || ...
               isempty(app.CurrentCellNumberVisualize) || ...
               app.CurrentTimepointIndexVisualize > numel(app.LoadedTimepointData) || ...
               isempty(app.LoadedTimepointData(app.CurrentTimepointIndexVisualize).name) || ...
               isempty(app.LoadedTimepointData(app.CurrentTimepointIndexVisualize).signal_info)

                % Nothing to plot—clear main axes and delete any old decay‐fits children
                cla(app.MainSignalAxes);
                title(app.MainSignalAxes, 'Select Timepoint and Cell');

                % Remove any old axes or labels inside the decay panel
                existingChildren = app.DecayFitsPanel.Children;
                for k = 1:numel(existingChildren)
                    delete(existingChildren(k));
                end
                return;
            end

            % Aliases for readability
            tp_idx   = app.CurrentTimepointIndexVisualize;
            cellNum  = app.CurrentCellNumberVisualize;
            data_tp  = app.LoadedTimepointData(tp_idx);
            sig_info = data_tp.signal_info;
            smooth   = data_tp.smooth_signal;
            cs_out   = data_tp.calcium_spikes_output;
            prm      = app.AnalysisSettings.ProminenceValue;

            fdata    = sig_info.fdata;
            time_vec = sig_info.time;

            % Verify that cellNum is within range
            if cellNum < 1 || cellNum > size(fdata,1) || cellNum > size(smooth,1)
                uialert(app.NeuroSpikeXPostProcessingUIFigure, ...
                       sprintf('Cell number %d is out of bounds for timepoint %s.', cellNum, app.TimepointNames{tp_idx}), ...
                       'Index Error');
                cla(app.MainSignalAxes);
                title(app.MainSignalAxes, 'Cell index error');

                % Clear old decay axes
                existingChildren = app.DecayFitsPanel.Children;
                for k = 1:numel(existingChildren)
                    delete(existingChildren(k));
                end
                return;
            end

            % ================================
            % 2) PLOT RAW & SMOOTHED SIGNAL (+ PEAKS/MINS IF ACTIVE)
            % ================================
            cla(app.MainSignalAxes);
            hold(app.MainSignalAxes, 'on');

            max_fdata_frames  = size(fdata,   2);
            max_smooth_frames = size(smooth,  2);
            max_time_frames   = length(time_vec);
            last_frame        = min([max_fdata_frames, max_smooth_frames, max_time_frames]);

            % Plot raw fluorescence
            plot(app.MainSignalAxes, ...
                time_vec(1:last_frame), ...
                fdata(cellNum, 1:last_frame), ...
                'Color', [0.7 0.7 0.7], 'DisplayName', 'Raw (from ProcessNeuroCa)');

            % Plot smoothed fluorescence
            plot(app.MainSignalAxes, ...
                time_vec(1:last_frame), ...
                smooth(cellNum, 1:last_frame), ...
                'b-', 'LineWidth', 1.5, 'DisplayName', 'Smoothed');

            % Determine if this cell is “active” by code
            is_active = false;
            row_idx   = [];
            if isfield(cs_out, 'active_cells') && ~isempty(cs_out.active_cells)
                list_act  = cs_out.active_cells(:,1);
                idx_match = find(cellNum == list_act, 1);
                if ~isempty(idx_match)
                    is_active = true;
                    row_idx   = idx_match;
                end
            end

            if is_active
                status_str = 'Active (by code)';
            else
                status_str = 'Inactive (by code)';
            end

            % Overlay peaks & decay minima if active
            if is_active && ...
               isfield(cs_out, 'true_peaks')     && size(cs_out.true_peaks, 1)     >= row_idx && ...
               isfield(cs_out, 'peak_locs')      && size(cs_out.peak_locs,  1)      >= row_idx && ...
               isfield(cs_out, 'decay_min_locs') && size(cs_out.decay_min_locs,1) >= row_idx

                true_pks  = cs_out.true_peaks(row_idx, :);
                peak_locs = cs_out.peak_locs(row_idx, :);
                min_locs  = cs_out.decay_min_locs(row_idx, :);

                valid_peak_mask = ~isnan(true_pks) & ~isnan(peak_locs) & ...
                                  peak_locs > 0 & peak_locs <= last_frame;
                pk_y = true_pks(valid_peak_mask);
                pk_x = peak_locs(valid_peak_mask);

                valid_min_mask = ~isnan(min_locs) & min_locs > 0 & min_locs <= last_frame & ...
                                 min_locs > peak_locs; 
                min_x_all = min_locs(valid_min_mask);
                min_y_all = smooth(cellNum, min_x_all);

                if ~isempty(pk_x)
                    plot(app.MainSignalAxes, time_vec(pk_x), pk_y, 'g.', 'MarkerSize', 20, 'DisplayName', 'Peaks');
                end
                if ~isempty(min_x_all)
                    plot(app.MainSignalAxes, time_vec(min_x_all), min_y_all, 'r.', 'MarkerSize', 20, 'DisplayName', 'Decay Ends');
                end

                title(app.MainSignalAxes, ...
                      sprintf('Cell #%d (%s) – %s – Peaks (Prm=%.1f)', ...
                              cellNum, app.TimepointNames{tp_idx}, status_str, prm));
            else
                title(app.MainSignalAxes, ...
                      sprintf('Cell #%d (%s) – %s', ...
                              cellNum, app.TimepointNames{tp_idx}, status_str));
            end

            xlabel(app.MainSignalAxes, 'Time (s)');
            ylabel(app.MainSignalAxes, 'dF/F (%)');
            if last_frame > 0
                xlim(app.MainSignalAxes, [time_vec(1), time_vec(last_frame)]);
            end
            legend(app.MainSignalAxes, 'show', 'Location', 'best');
            hold(app.MainSignalAxes, 'off');

            % ================================
            % 3) DECAY FITS PANEL: MANUAL STACKING
            % ================================

            % (a) First, clear out any existing axes or labels inside DecayFitsPanel
            existingChildren = app.DecayFitsPanel.Children;
            for k = 1:numel(existingChildren)
                delete(existingChildren(k));
            end

            % (b) If this cell is inactive or missing data, put a label and return
            if ~is_active || ...
               ~isfield(cs_out, 'true_peaks')     || ...
               ~isfield(cs_out, 'peak_locs')      || ...
               ~isfield(cs_out, 'decay_min_locs') || ...
               size(cs_out.true_peaks, 1)     < row_idx || ...
               size(cs_out.peak_locs, 1)      < row_idx || ...
               size(cs_out.decay_min_locs, 1) < row_idx

                uilabel(app.DecayFitsPanel, ...
                        'Text', 'No transients to display for this cell.', ...
                        'FontSize', 14, ...
                        'Position', [10, 10, app.DecayFitsPanel.Position(3)-20, 30]);
                return;
            end

            % (c) Gather transient peaks/minima and build valid_mask
            all_pks      = cs_out.true_peaks(row_idx, :);
            all_pk_locs  = cs_out.peak_locs(row_idx, :);
            all_min_locs = cs_out.decay_min_locs(row_idx, :);
            valid_mask   = ~isnan(all_pks) & ~isnan(all_pk_locs) & ~isnan(all_min_locs) & ...
                           all_pk_locs > 0 & all_min_locs > all_pk_locs & ...
                           all_pk_locs <= max_fdata_frames  & all_min_locs <= max_fdata_frames & ...
                           all_pk_locs <= max_time_frames   & all_min_locs <= max_time_frames;

            % Turn that mask into a list of indices (each is a scalar)
            valid_indices = find(valid_mask);
            num_valid     = numel(valid_indices);
            if num_valid == 0
                uilabel(app.DecayFitsPanel, ...
                        'Text', 'No valid transients found for decay fitting.', ...
                        'FontSize', 14, ...
                        'Position', [10, 10, app.DecayFitsPanel.Position(3)-20, 30]);
                return;
            end

            % (d) Decide on pixel dimensions
            panelWidth   = app.DecayFitsPanel.Position(3);
            axisHeight   = 220;   % each fit‐axes is 220 px tall
            axisWidth    = panelWidth - 20;  % leave a 10 px margin left/right
            verticalGap  = 10;    % gap in px between successive axes
            totalHeight  = num_valid * (axisHeight + verticalGap) + verticalGap;

            % (e) Finally, make the panel scrollable
            app.DecayFitsPanel.Scrollable = 'on';

            % (f) Now create one uiaxes per valid transient, stacked from top to bottom
            for idx = 1:num_valid
                j = valid_indices(idx);  % a scalar index into all_pk_locs/all_min_locs

                peak_frame = all_pk_locs(j);
                end_frame  = all_min_locs(j);

                % Both peak_frame and end_frame are guaranteed to be real scalars
                x_data = time_vec(peak_frame:end_frame) - time_vec(peak_frame);
                y_data = fdata(cellNum, peak_frame:end_frame) - fdata(cellNum, end_frame);

                if numel(x_data) < 2
                    continue;
                end

                % Compute the bottom‐left corner (xpos, ypos) so that each row stacks
                ypos = totalHeight - (idx)*(axisHeight + verticalGap) + verticalGap;
                xpos = 10;  % 10 px from left edge

                ax = uiaxes(app.DecayFitsPanel);
                ax.Position = [xpos, ypos, axisWidth, axisHeight];

                % Plot inside this axes
                try
                    [fit_obj, gof] = fit(x_data', y_data', 'exp1');
                    coeffs = coeffvalues(fit_obj);
                    plot(ax, fit_obj, x_data, y_data);
                    legend(ax, 'Signal Segment', 'Exp. Fit', 'Location', 'northeast');
                    title(ax, sprintf('Transient #%d (Rate Constant: %.3f)', j, -coeffs(2)), 'FontSize', 12);
                    txt = sprintf('Y=%.1f·exp(%.2f·X)\nR^2=%.2f', coeffs(1), coeffs(2), gof.rsquare);
                    text(ax, 0.05, 0.05, txt, 'Units','normalized', ...
                         'VerticalAlignment','bottom', 'FontSize', 12);
                catch ME_fit
                    plot(ax, x_data, y_data, 'b-');
                    title(ax, sprintf('Transient #%d (Fit Err)', j), 'FontSize', 12);
                    text(ax, 0.5, 0.5, ME_fit.message, ...
                         'HorizontalAlignment','center', 'FontSize',12, ...
                         'Color','r', 'Interpreter','none');
                end

                xlabel(ax, 'Time from Peak (s)');
                ylabel(ax, 'dF/F (%)');
                grid(ax, 'on');
            end

            % Compute how tall the panel is on screen:
            panelHeight = app.DecayFitsPanel.Position(4);
            
            % Scroll so that contentHeight==totalHeight appears aligned at the top:
            scrollY = max(0, totalHeight - panelHeight);
            
            % Scroll horizontally to 0 (left) and vertically to scrollY:
            app.DecayFitsPanel.ScrollableViewportLocation = [0, scrollY];
            
            drawnow('expose');

        end


        function prepareForValidation(app)
            if isempty(app.LoadedTimepointData) || isempty(app.LoadedTimepointData(1).name)
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'Load data first.', 'Data Missing');
                return;
            end
        
            percentage = app.PercentageEditField.Value;
            if isnan(percentage) || percentage <= 0 || percentage > 100
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'Percentage must be 0–100.', 'Invalid Input');
                return;
            end
        
            app.CellsForValidation = {};
            for tp = 1:numel(app.LoadedTimepointData)
                dataTP = app.LoadedTimepointData(tp);
                if isempty(dataTP.name) || isempty(dataTP.fdata) || isempty(dataTP.calcium_spikes_output)
                    continue;
                end
        
                % --- use the full fdata, not 2:end ---
                fdata_val = dataTP.signal_info.fdata;  
                nCells    = size(fdata_val, 1);
                if nCells == 0, continue; end
        
                nSelect = ceil((percentage/100)*nCells);
                if nSelect==0, nSelect=1; end
                selected = randperm(nCells, nSelect);
        
                % get code's active list & transient counts
                cs = dataTP.calcium_spikes_output;
                codeActive = [];
                if isfield(cs, 'active_cells') && ~isempty(cs.active_cells)
                    codeActive = cs.active_cells(:,1);
                end
                for c = selected
                    isAct = any(c==codeActive);
                    tcount = 0;
                    if isAct && isfield(cs,'true_peaks') && ~isempty(cs.true_peaks)
                        idx = find(c==codeActive,1);
                        if ~isempty(idx)
                            tcount = sum(~isnan(cs.true_peaks(idx,:)));
                        end
                    end
        
                    app.CellsForValidation{end+1} = struct( ...
                        'tpIdx', tp, ...
                        'cellNum', c, ...         % now matches full fdata row
                        'code_is_active', isAct, ...
                        'manual_is_active', NaN, ...
                        'code_transient_count', tcount, ...
                        'manual_transient_count', NaN ...
                    );
                end
            end
        
            if isempty(app.CellsForValidation)
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'No cells to validate.', 'Validation Error');
                return;
            end
        
            app.TotalCellsForValidation   = numel(app.CellsForValidation);
            app.CurrentValidationCellIndex = 1;
            app.StartValidationButton.Enable = 'off';
            displayNextValidationCell(app);
        end

        function displayNextValidationCell(app)
            % ====================================================
            % 1) CHECK FOR COMPLETION
            % ====================================================
            if app.CurrentValidationCellIndex < 1 || ...
               app.CurrentValidationCellIndex > app.TotalCellsForValidation
        
                % Finish up
                if strcmp(app.CurrentValidationMode, 'cell')
                    calculateAndShowCellAccuracyMetrics(app);
                else
                    calculateAndShowTransientCountMetrics(app);
                end
                app.ValidationProgressLabel.Text = 'Validation Complete!';
                cla(app.ValidationSignalAxes);
                title(app.ValidationSignalAxes, 'Validation Complete');
                app.StartValidationButton.Enable              = 'on';
                app.IsSignalButton.Enable                     = 'off';
                app.IsNoiseButton.Enable                      = 'off';
                app.SubmitTransientCountButton.Enable         = 'off';
                app.TransientCountEditField.Enable            = 'off';
                return;
            end
        
            % ====================================================
            % 2) LOAD THE CURRENT ENTRY
            % ====================================================
            entry        = app.CellsForValidation{app.CurrentValidationCellIndex};
            tpIdx        = entry.tpIdx;
            cellIdx      = entry.cellNum;
            sigInfo      = app.LoadedTimepointData(tpIdx).signal_info;
            smoothSignal = app.LoadedTimepointData(tpIdx).smooth_signal;
        
            % ====================================================
            % 3) GRAB FULL fdata AND GUARD AGAINST OOB
            % ====================================================
            fdata_disp = sigInfo.fdata;      % <— use the entire matrix, no (2:end)
        
            nRows = size(fdata_disp,1);
            if cellIdx < 1 || cellIdx > nRows
                warning('Cell index %d is out of bounds for TP "%s" (%d cells). Skipping.', ...
                        cellIdx, app.TimepointNames{tpIdx}, nRows);
                % advance to next and recurse
                app.CurrentValidationCellIndex = app.CurrentValidationCellIndex + 1;
                displayNextValidationCell(app);
                return;
            end
        
            % ====================================================
            % 4) ALIGN RAW & SMOOTHED TO THE SAME LAST_FRAME
            % ====================================================
            % Time axis is separate
            time_axis_disp = sigInfo.time;
        
            last_frame = min([ ...
                size(fdata_disp,     2), ...
                size(smoothSignal,   2), ...
                numel(time_axis_disp)    ...
            ]);
        
            raw_trace    = fdata_disp(cellIdx,    1:last_frame);
            smooth_trace = smoothSignal(cellIdx,  1:last_frame);
        
            % ====================================================
            % 5) PLOT RAW vs SMOOTHED
            % ====================================================
            cla(app.ValidationSignalAxes);
            hold(app.ValidationSignalAxes, 'on');
            plot(app.ValidationSignalAxes, time_axis_disp(1:last_frame), raw_trace,    ...
                 'Color',[0.7 0.7 0.7], 'DisplayName','Raw');
            plot(app.ValidationSignalAxes, time_axis_disp(1:last_frame), smooth_trace, ...
                 'b-', 'LineWidth',1.5,   'DisplayName','Smoothed');
            hold(app.ValidationSignalAxes, 'off');
        
            title(app.ValidationSignalAxes, sprintf('Timepoint: %s, Cell #%d', ...
                  app.TimepointNames{tpIdx}, cellIdx));
            xlabel(app.ValidationSignalAxes, 'Time (s)');
            ylabel(app.ValidationSignalAxes, 'dF/F (%)');
            legend(app.ValidationSignalAxes, 'Location','best');
        
            % ====================================================
            % 6) UPDATE LABEL & ENABLE BUTTONS
            % ====================================================
            app.ValidationProgressLabel.Text = sprintf( ...
                'Reviewing Cell %d of %d (Timepoint: %s)', ...
                app.CurrentValidationCellIndex, ...
                app.TotalCellsForValidation, ...
                app.TimepointNames{tpIdx});
        
            if strcmp(app.CurrentValidationMode, 'cell')
                app.IsSignalButton.Enable             = 'on';
                app.IsNoiseButton.Enable              = 'on';
                app.SubmitTransientCountButton.Enable = 'off';
                app.TransientCountEditField.Enable    = 'off';
            else  % 'transient' mode
                app.IsSignalButton.Enable             = 'off';
                app.IsNoiseButton.Enable              = 'off';
                app.TransientCountEditField.Value     = 0;
                app.TransientCountEditField.Enable    = 'on';
                app.SubmitTransientCountButton.Enable = 'on';
                drawnow;
                if strcmp(app.TransientCountEditField.Enable,'on')
                    focus(app.TransientCountEditField);
                end
            end
        end

        function recordValidationChoice(app, isSignal) % For cell mode
            if app.CurrentValidationCellIndex == 0 || app.CurrentValidationCellIndex > app.TotalCellsForValidation || isempty(app.CellsForValidation)
                return; 
            end
            app.CellsForValidation{app.CurrentValidationCellIndex}.manual_is_active = isSignal;
            app.CurrentValidationCellIndex = app.CurrentValidationCellIndex + 1;
            displayNextValidationCell(app);
        end
        
        function calculateAndShowCellAccuracyMetrics(app)
            % ====================================================
            % 0) ENSURE ACCURACYMETRICSRESULTS IS A SCALAR STRUCT
            % ====================================================
            if ~isstruct(app.AccuracyMetricsResults) || numel(app.AccuracyMetricsResults)~=1
                app.AccuracyMetricsResults = struct();
            end
        
            % ====================================================
            % 1) EARLY EXIT IF NO VALIDATION DATA
            % ====================================================
            if isempty(app.CellsForValidation)
                uialert(app.NeuroSpikeXPostProcessingUIFigure, ...
                    'No validation data to process for cell accuracy.', 'Info');
                app.AccuracyResultsTable.Data = [];
                return;
            end
        
            % ====================================================
            % 2) SET UP PER‐TIMEPOINT & OVERALL COUNTERS
            % ====================================================
            nTP = numel(app.LoadedTimepointData);
            perTP = repmat(...
                struct('TP',0,'TN',0,'FP',0,'FN',0,'TotalReviewed',0,'Name',''), ...
                nTP, 1);
        
            % Fill names
            for i = 1:nTP
                nm = app.LoadedTimepointData(i).name;
                if isempty(nm)
                    perTP(i).Name = sprintf('TP %d', i);
                else
                    perTP(i).Name = nm;
                end
            end
        
            overall = struct('TP',0,'TN',0,'FP',0,'FN',0,'TotalReviewed',0);
        
            % ====================================================
            % 3) TALLY TP‐BY‐TP AGAINST MANUAL CHOICES
            % ====================================================
            for k = 1:numel(app.CellsForValidation)
                e = app.CellsForValidation{k};
                if isnan(e.manual_is_active), continue; end
        
                tp = e.tpIdx;
                perTP(tp).TotalReviewed = perTP(tp).TotalReviewed + 1;
                overall.TotalReviewed   = overall.TotalReviewed   + 1;
        
                if  e.manual_is_active &&  e.code_is_active
                    perTP(tp).TP       = perTP(tp).TP + 1;
                    overall.TP         = overall.TP   + 1;
                elseif ~e.manual_is_active && ~e.code_is_active
                    perTP(tp).TN       = perTP(tp).TN + 1;
                    overall.TN         = overall.TN   + 1;
                elseif ~e.manual_is_active &&  e.code_is_active
                    perTP(tp).FP       = perTP(tp).FP + 1;
                    overall.FP         = overall.FP   + 1;
                elseif  e.manual_is_active && ~e.code_is_active
                    perTP(tp).FN       = perTP(tp).FN + 1;
                    overall.FN         = overall.FN   + 1;
                end
            end
        
            % ====================================================
            % 4) SAVE INTO THE APP PROPERTY
            % ====================================================
            app.AccuracyMetricsResults.perTimepoint = perTP;
            app.AccuracyMetricsResults.overall      = overall;
        
            % ====================================================
            % 5) BUILD TABLE DATA & SHOW IN UIFIGURE
            % ====================================================
            header = {...
              'Timepoint','True Positive','True Negative','False Positive','False Negative',...
              'Sensitivity','Specificity','Accuracy','FP Ratio','FN Ratio','Youden J'};
            rows = {};
        
            % Append an OVERALL row at the end
            allMetrics = [perTP; struct(...
                'TP',overall.TP,'TN',overall.TN,...
                'FP',overall.FP,'FN',overall.FN,...
                'TotalReviewed',overall.TotalReviewed,...
                'Name','OVERALL')];
        
            for i = 1:numel(allMetrics)
                m = allMetrics(i);
                N = m.TotalReviewed;
                if N==0 && ~strcmp(m.Name,'OVERALL'), continue; end
        
                TP = m.TP; TN = m.TN; FP = m.FP; FN = m.FN;
                sens = TP/(TP+FN);    if (TP+FN)==0, sens=NaN; end
                spec = TN/(TN+FP);    if (TN+FP)==0, spec=NaN; end
                acc  = (TP+TN)/N;     if N==0, acc=NaN;    end
                fpr  = FP/(FP+TN);    if (FP+TN)==0, fpr=NaN; end
                fnr  = FN/(FN+TP);    if (FN+TP)==0, fnr=NaN; end
                youd = sens + spec - 1;
        
                rows(end+1,:) = { ...
                  m.Name, TP, TN, FP, FN, ...
                  sprintf('%.3f',sens), sprintf('%.3f',spec), sprintf('%.3f',acc), ...
                  sprintf('%.3f',fpr), sprintf('%.3f',fnr), sprintf('%.3f',youd) ...
                };
            end
        
            app.AccuracyResultsTable.ColumnName = header;
            app.AccuracyResultsTable.Data       = rows;
        
            % ====================================================
            % 6) SAVE OUT MAT FILE
            % ====================================================
            try
                save(fullfile(app.RootFolderPath, 'PostProcessApp_CellAccuracyMetrics.mat'), ...
                    'header', 'rows');
                uialert(app.NeuroSpikeXPostProcessingUIFigure, ...
                    'Cell accuracy metrics saved to PostProcessApp_CellAccuracyMetrics.mat', ...
                    'Save Complete', ...
                    'Icon','success');
            catch ME
                uialert(app.NeuroSpikeXPostProcessingUIFigure, ...
                    ['Could not save metrics: ' ME.message], 'Save Error');
            end
        end

        function calculateAndShowTransientCountMetrics(app)
        
            % 1. INITIAL SETUP
            % =================================
            if isempty(app.CellsForValidation)
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'No validation data to process.', 'Info');
                return;
            end
        
            num_timepoints = numel(app.LoadedTimepointData);
            perTimepointMetrics = repmat(struct('Name', '', 'CellDifferences', [], 'CellsValidated', 0, 'PerfectMatch', 0, 'WithinTolerance', 0, 'SumManual', 0, 'SumCode', 0), num_timepoints, 1);
        
            for i = 1:num_timepoints
                if isempty(app.LoadedTimepointData(i).name) || strlength(app.LoadedTimepointData(i).name) == 0
                    perTimepointMetrics(i).Name = sprintf('Timepoint Idx %d', i);
                else
                    perTimepointMetrics(i).Name = app.LoadedTimepointData(i).name;
                end
            end
            
            numCellsForValidation = numel(app.CellsForValidation);
            individualCellData = cell(numCellsForValidation, 6);
            individualCellHeaders = {'Timepoint', 'Cell #', 'Code Count', 'Manual Count', 'Difference', 'Status'};
            
            tolerance = 1;
        
            % 2. GATHER DATA FROM ALL CELLS
            % =================================
            % We will also build the 'all_counts' vectors here directly, which is more robust.
            all_manual_counts = NaN(numCellsForValidation, 1);
            all_code_counts = NaN(numCellsForValidation, 1);
        
            for i = 1:numCellsForValidation
                entry = app.CellsForValidation{i};
                if isnan(entry.manual_transient_count), continue; end 
                tpIdx = entry.tpIdx;
                if tpIdx > num_timepoints || tpIdx < 1, continue; end
        
                % Store the raw counts for later overall calculations
                all_manual_counts(i) = entry.manual_transient_count;
                all_code_counts(i) = entry.code_transient_count;
                
                perTimepointMetrics(tpIdx).CellsValidated = perTimepointMetrics(tpIdx).CellsValidated + 1;
                perTimepointMetrics(tpIdx).SumManual = perTimepointMetrics(tpIdx).SumManual + entry.manual_transient_count;
                perTimepointMetrics(tpIdx).SumCode = perTimepointMetrics(tpIdx).SumCode + entry.code_transient_count;
        
                difference = entry.code_transient_count - entry.manual_transient_count;
                perTimepointMetrics(tpIdx).CellDifferences = [perTimepointMetrics(tpIdx).CellDifferences; difference];
        
                if difference == 0
                    perTimepointMetrics(tpIdx).PerfectMatch = perTimepointMetrics(tpIdx).PerfectMatch + 1;
                    status_str = 'Match';
                elseif difference > 0
                    status_str = 'Code Over-counted';
                else
                    status_str = 'Code Under-counted';
                end
                
                if abs(difference) <= tolerance
                    perTimepointMetrics(tpIdx).WithinTolerance = perTimepointMetrics(tpIdx).WithinTolerance + 1;
                end
                
                individualCellData(i,:) = {app.TimepointNames{tpIdx}, entry.cellNum, entry.code_transient_count, entry.manual_transient_count, difference, status_str};
            end
            
            % Clean up the count vectors by removing NaNs from skipped cells
            valid_mask = ~isnan(all_manual_counts);
            all_manual_counts = all_manual_counts(valid_mask);
            all_code_counts = all_code_counts(valid_mask);
            all_differences = all_code_counts - all_manual_counts;
            
            % Store for histogram
            app.HistogramDifferences = all_differences;
        
            % 3. BUILD THE DISPLAY CELL ARRAY
            % =================================
            summaryHeaders = {'Timepoint', '% Perfect Match', '% Match (+-1 spike)', 'Mean Abs Error (peaks)', 'RMSE (peaks)', 'Code Bias (peaks)', 'SMAPE (%)', 'Total Manual (peaks)', 'Total Code (peaks)', 'Cells Reviewed'};
            summaryData_ForDisplay = cell(num_timepoints + 1, numel(summaryHeaders));
        
            % --- PER-TIMEPOINT LOOP ---
            for i = 1:num_timepoints
                ptm = perTimepointMetrics(i);
                
                if ptm.CellsValidated == 0
                    summaryData_ForDisplay(i, 1:10) = {ptm.Name, 'N/A', 'N/A', 'N/A', 'N/A', 'N/A', 'N/A', 0, 0, 0};
                    continue;
                end
        
                diffs_tp = ptm.CellDifferences;
                
                % Robust Per-Timepoint SMAPE Calculation
                tp_mask = (cellfun(@(c) c.tpIdx, app.CellsForValidation(valid_mask)) == i);
                codeCounts_tp = all_code_counts(tp_mask);
                manualCounts_tp = all_manual_counts(tp_mask);
                
                denominator_tp = abs(codeCounts_tp) + abs(manualCounts_tp);
                smape_values_tp = 2 * abs(diffs_tp) ./ denominator_tp;
                smape_values_tp(denominator_tp == 0) = 0;
                val_smape = mean(smape_values_tp, 'omitnan') * 100;
        
                % Assign each value individually to avoid bugs
                summaryData_ForDisplay{i, 1} = ptm.Name;
                summaryData_ForDisplay{i, 2} = sprintf('%.1f%%', (ptm.PerfectMatch / ptm.CellsValidated) * 100);
                summaryData_ForDisplay{i, 3} = sprintf('%.1f%%', (ptm.WithinTolerance / ptm.CellsValidated) * 100);
                summaryData_ForDisplay{i, 4} = sprintf('%.2f', mean(abs(diffs_tp)));
                summaryData_ForDisplay{i, 5} = sprintf('%.2f', sqrt(mean(diffs_tp.^2)));
                summaryData_ForDisplay{i, 6} = sprintf('%+.2f', mean(diffs_tp));
                summaryData_ForDisplay{i, 7} = sprintf('%.2f%%', val_smape);
                summaryData_ForDisplay{i, 8} = sprintf('%d', ptm.SumManual); % CORRECTED: ONE UNDERSCORE
                summaryData_ForDisplay{i, 9} = sprintf('%d', ptm.SumCode);
                summaryData_ForDisplay{i, 10} = sprintf('%d', ptm.CellsValidated);
            end
        
            % 4. "OVERALL" ROW
            % ======================================
            overall_CellsValidated = sum([perTimepointMetrics.CellsValidated]);
            overall_row_index = num_timepoints + 1;
            
            if overall_CellsValidated > 0
                overall_denominator = abs(all_code_counts) + abs(all_manual_counts);
                overall_smape_values = 2 * abs(all_differences) ./ overall_denominator;
                overall_smape_values(overall_denominator == 0) = 0;
                val_overall_smape = mean(overall_smape_values, 'omitnan') * 100;
                
                summaryData_ForDisplay{overall_row_index, 1} = 'OVERALL';
                summaryData_ForDisplay{overall_row_index, 2} = sprintf('%.1f%%', (sum([perTimepointMetrics.PerfectMatch]) / overall_CellsValidated) * 100);
                summaryData_ForDisplay{overall_row_index, 3} = sprintf('%.1f%%', (sum([perTimepointMetrics.WithinTolerance]) / overall_CellsValidated) * 100);
                summaryData_ForDisplay{overall_row_index, 4} = sprintf('%.2f', mean(abs(all_differences)));
                summaryData_ForDisplay{overall_row_index, 5} = sprintf('%.2f', sqrt(mean(all_differences.^2)));
                summaryData_ForDisplay{overall_row_index, 6} = sprintf('%+.2f', mean(all_differences));
                summaryData_ForDisplay{overall_row_index, 7} = sprintf('%.2f%%', val_overall_smape);
                summaryData_ForDisplay{overall_row_index, 8} = sprintf('%d', sum([perTimepointMetrics.SumManual]));
                summaryData_ForDisplay{overall_row_index, 9} = sprintf('%d', sum([perTimepointMetrics.SumCode]));
                summaryData_ForDisplay{overall_row_index, 10} = sprintf('%d', overall_CellsValidated);
            else
                summaryData_ForDisplay(overall_row_index, :) = {'OVERALL', 'N/A', 'N/A', 'N/A', 'N/A', 'N/A', 'N/A', 0, 0, 0};
            end
            
            % 5. UPDATE UI
            % =========================
            app.TransientCountResultsTable.ColumnName = summaryHeaders;
            app.TransientCountResultsTable.Data = summaryData_ForDisplay;
            app.TransientCountResultsTable.ColumnWidth = 'auto';
        
            % 6. UPDATE HISTOGRAM
            % =====================
            if ~isempty(all_differences)
                histogram(app.TransientErrorHistogramAxes, all_differences, 'BinMethod', 'integer');
                % ... histogram formatting ...
            end
        
            % 7. SAVE RESULTS
            % =========================
            try
                save(fullfile(app.RootFolderPath, 'PostProcessApp_TransientCountMetrics.mat'), ...
                     'summaryData_ForDisplay', 'summaryHeaders', 'individualCellData', 'individualCellHeaders', 'all_differences');
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'Metrics saved to MAT file.', 'Save Complete', 'Icon', 'success');
            catch ME_save
                uialert(app.NeuroSpikeXPostProcessingUIFigure, ['Could not save metrics: ' ME_save.message], 'Save Error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Visualize Calculations Panel
            app.RootFolderLabelVisualize.Text = "Root Folder: Not Loaded";
            app.RootFolderLabelAccuracy.Text = "Root Folder: Not Loaded";
            app.TimepointDropDownVisualize.Items = {'Load Data First'};
            app.TimepointDropDownVisualize.Value = app.TimepointDropDownVisualize.Items{1};
            app.ActiveCellsDropDown.Items = {'-'}; 
            app.ActiveCellsDropDown.ItemsData = []; % Ensure ItemsData is empty cell or matching placeholder
            app.ActiveCellsDropDown.Value = app.ActiveCellsDropDown.Items{1};
            
            app.InactiveCellsDropDown.Items = {'-'}; 
            app.InactiveCellsDropDown.ItemsData = [];
            app.InactiveCellsDropDown.Value = app.InactiveCellsDropDown.Items{1};

            % --- Configure DecayFitsGridLayout Statically ---
            % app.DecayFitsGridLayout.Parent = app.DecayFitsPanel; % Ensure parentage
            if isempty(app.DecayFitsGridLayout) || ~isvalid(app.DecayFitsGridLayout)
                app.DecayFitsGridLayout = uigridlayout(app.DecayFitsPanel);
            else
                delete(app.DecayFitsGridLayout.Children); % Ensure it's empty
            end

            % Ensure the panel is scrollable
            app.DecayFitsPanel.Scrollable = 'off';
            app.DecayFitsGridLayout.Scrollable = 'on';
            
            % Accuracy Panel 
            app.RecommendedPercentageLabel.Text = "Recommended: 10-20% for robust validation (or as per publication standards).";
            app.PercentageEditField.Value = 10; 

            disableVisualizeControls(app, true);
            disableAccuracyControls(app, true); % Disables most accuracy controls initially
            
            app.AccuracyResultsTable.Data = [];
            app.AccuracyMetricsLabel.Text = 'Accuracy Metrics (per timepoint and overall)';
            app.TransientCountResultsTable.Data = [];
            app.TransientCountMetricsLabel.Text = 'Transient Count Metrics:';
            
            app.CurrentValidationMode = 'cell'; 
            updateAccuracyUIForMode(app); 
        end

        % Button pushed function: LoadDataButtonVisualize
        function LoadDataButtonVisualizePushed(app, event)
            loadDataForApp(app);
        end

        % Value changed function: TimepointDropDownVisualize
        function TimepointDropDownVisualizeValueChanged(app, event)
            if isempty(app.TimepointNames), return; end
            selectedTPName = app.TimepointDropDownVisualize.Value;
            idx = find(strcmp(app.TimepointNames, selectedTPName));
            if ~isempty(idx)
                app.CurrentTimepointIndexVisualize = idx(1); % Take first if multiple (shouldn't happen)
                updateActiveInactiveCellDropDownVisualize(app);
            end
        end

        % Button pushed function: LoadDataButtonAccuracy
        function LoadDataButtonAccuracyPushed(app, event)
            if ~isempty(app.RootFolderPath) && ~isempty(app.LoadedTimepointData) && ~isempty(app.LoadedTimepointData(1).name)
                response = uiconfirm(app.NeuroSpikeXPostProcessingUIFigure, 'Data is already loaded. Do you want to select a new root folder and reload all data?', 'Confirm Reload', ...
                    'Options', {'Yes, Reload', 'No, Use Current'}, 'DefaultOption', 2, 'Icon','question');
                if strcmp(response, 'No, Use Current')
                    if ~isempty(app.LoadedTimepointData) && ~isempty(app.LoadedTimepointData(1).name)
                        disableAccuracyControls(app, false); % Enable general controls
                        app.StartValidationButton.Enable = 'on'; % Ensure start is enabled
                        resetValidationState(app); % Call this to set idle state correctly
                    end
                    return;
                end
            end
            loadDataForApp(app);
        end

        % Value changed function: ValidationModeDropDown
        function ValidationModeDropDownValueChanged(app, event)
            app.CurrentValidationMode = app.ValidationModeDropDown.Value;
            updateAccuracyUIForMode(app);
            % If validation was in progress, reset it
            if strcmp(app.StartValidationButton.Enable,'off') 
                resetValidationState(app);
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'Validation mode changed. Validation has been reset. Please click "Start Manual Validation" again.', 'Mode Changed');
            end
        end

        % Button pushed function: StartValidationButton
        function StartValidationButtonPushed(app, event)
            % --- First, ensure the correct UI elements are visible ---
            updateAccuracyUIForMode(app);
        
            % --- Then, prepare the data for the validation session ---
            prepareForValidation(app);
        end

        % Button pushed function: IsSignalButton
        function IsSignalButtonPushed(app, event)
            recordValidationChoice(app, true);
        end

        % Button pushed function: IsNoiseButton
        function IsNoiseButtonPushed(app, event)
            recordValidationChoice(app, false); 
        end

        % Button pushed function: SubmitTransientCountButton
        function SubmitTransientCountButtonPushed(app, event)
            if app.CurrentValidationCellIndex == 0 || app.CurrentValidationCellIndex > app.TotalCellsForValidation || isempty(app.CellsForValidation)
                return;
            end
            manual_count = app.TransientCountEditField.Value;
             if isnan(manual_count) || manual_count < 0 || floor(manual_count) ~= manual_count
                uialert(app.NeuroSpikeXPostProcessingUIFigure, 'Please enter a valid non-negative integer for transient count before submitting.', 'Invalid Input');
                return;
            end

            app.CellsForValidation{app.CurrentValidationCellIndex}.manual_transient_count = manual_count;
            app.CellsForValidation{app.CurrentValidationCellIndex}.manual_is_active = (manual_count > 0); 

            app.CurrentValidationCellIndex = app.CurrentValidationCellIndex + 1;
            displayNextValidationCell(app); % Will also reset edit field for next cell

            % After displayNextValidationCell, if validation is not complete, set focus
            if app.CurrentValidationCellIndex <= app.TotalCellsForValidation && ...
                strcmp(app.CurrentValidationMode, 'transient') && ...
                strcmp(app.TransientCountEditField.Enable, 'on')
                drawnow; % Ensure UI updates are processed
                focus(app.TransientCountEditField); % Set focus back
            end
        end

        % Value changed function: ActiveCellsDropDown
        function ActiveCellsDropDownValueChanged(app, event)
            if ~isempty(app.ActiveCellsDropDown.ItemsData) && ~isempty(app.ActiveCellsDropDown.Value)
                % Ensure Value is part of ItemsData to prevent errors if Items are cleared and Value isn't
                if any(app.ActiveCellsDropDown.Value == app.ActiveCellsDropDown.ItemsData)
                    app.CurrentCellNumberVisualize = app.ActiveCellsDropDown.Value;
                    plotIndividualCellAnalysis(app);
                else 
                    % This case might happen if items are changed and old value is no longer valid
                    % You could select the first item again or clear the plot
                    if ~isempty(app.ActiveCellsDropDown.ItemsData)
                        app.ActiveCellsDropDown.Value = app.ActiveCellsDropDown.ItemsData(1);
                        app.CurrentCellNumberVisualize = app.ActiveCellsDropDown.Value;
                        plotIndividualCellAnalysis(app);
                    else
                        % Handle case where ItemsData becomes empty if necessary
                        app.CurrentCellNumberVisualize = [];
                        cla(app.MainSignalAxes); title(app.MainSignalAxes, 'No active cells available');
                        delete(app.DecayFitsGridLayout.Children);
                    end
                end
            end
        end

        % Value changed function: InactiveCellsDropDown
        function InactiveCellsDropDownValueChanged(app, event)
            if ~isempty(app.InactiveCellsDropDown.ItemsData) && ~isempty(app.InactiveCellsDropDown.Value)
                if any(app.InactiveCellsDropDown.Value == app.InactiveCellsDropDown.ItemsData)
                   app.CurrentCellNumberVisualize = app.InactiveCellsDropDown.Value;
                   plotIndividualCellAnalysis(app);
               else
                   if ~isempty(app.InactiveCellsDropDown.ItemsData)
                       app.InactiveCellsDropDown.Value = app.InactiveCellsDropDown.ItemsData(1);
                       app.CurrentCellNumberVisualize = app.InactiveCellsDropDown.Value;
                       plotIndividualCellAnalysis(app);
                   else
                       app.CurrentCellNumberVisualize = [];
                       cla(app.MainSignalAxes); title(app.MainSignalAxes, 'No inactive cells available');
                       delete(app.DecayFitsGridLayout.Children);
                   end
               end
           end
        end

        % Window key press function: NeuroSpikeXPostProcessingUIFigure
        function NeuroSpikeXPostProcessingUIFigureWindowKeyPress(app, event)
             if isequal(app.MainTabGroup.SelectedTab, app.AccuracyTab)
                % Check if validation is active (Start button is off AND one of the action buttons is on)
                validationActiveCellMode = strcmp(app.StartValidationButton.Enable, 'off') && strcmp(app.IsSignalButton.Enable, 'on');
                validationActiveTransientMode = strcmp(app.StartValidationButton.Enable, 'off') && strcmp(app.SubmitTransientCountButton.Enable, 'on');

                if strcmp(app.CurrentValidationMode, 'cell') && validationActiveCellMode
                    switch event.Key
                        case {'s', 'S'}
                            IsSignalButtonPushed(app, event);
                        case {'n', 'N'}
                            IsNoiseButtonPushed(app, event);
                    end
                elseif strcmp(app.CurrentValidationMode, 'transient') && validationActiveTransientMode
                    if strcmp(event.Key, 'return') 
                        % Check if focus is on the edit field to prevent accidental submits
                        % This is tricky with UIFigure KeyPress. A simpler approach is just to use the button.
                        % For more robust enter key handling on edit field, it's better to use
                        % KeyPressedFcn directly on the TransientCountEditField itself.
                        % However, for simplicity with UIFigure KeyPress:
                        SubmitTransientCountButtonPushed(app, event);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroSpikeXPostProcessingUIFigure = uifigure('Visible', 'off');
            app.NeuroSpikeXPostProcessingUIFigure.Name = 'NeuroSpikeX Post-Processing';
            app.NeuroSpikeXPostProcessingUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @NeuroSpikeXPostProcessingUIFigureWindowKeyPress, true);
            app.MainTabGroup = uitabgroup(app.NeuroSpikeXPostProcessingUIFigure);
            app.VisualizeTab = uitab(app.MainTabGroup);
            app.VisualizeTab.Title = 'Visualize Cell Analysis';
            app.GridLayout = uigridlayout(app.VisualizeTab);
            app.MainSignalAxes = uiaxes(app.GridLayout);
            title(app.MainSignalAxes, 'Cell Signal & Detected Peaks')
            xlabel(app.MainSignalAxes, 'Time (s)')
            ylabel(app.MainSignalAxes, 'dF/F (%)')
            zlabel(app.MainSignalAxes, 'Z')
            app.LoadDataButtonVisualize = uibutton(app.GridLayout, 'push');
            app.LoadDataButtonVisualize.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonVisualizePushed, true);
            app.LoadDataButtonVisualize.Text = 'Load Processed Data';
            app.RootFolderLabelVisualize = uilabel(app.GridLayout);
            app.RootFolderLabelVisualize.Text = 'Sample Root Folder: Not Loaded';
            app.TimepointLabelVisualize = uilabel(app.GridLayout);
            app.TimepointLabelVisualize.Text = 'Timepoint:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.TimepointDropDownVisualize = uidropdown(app.GridLayout);
            app.TimepointDropDownVisualize.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownVisualizeValueChanged, true);
            app.DecayFitsPanel = uipanel(app.GridLayout);
            app.DecayFitsPanel.Title = 'Individual Transient Decay Fits';
            app.DecayFitsGridLayout = uigridlayout(app.DecayFitsPanel);
            app.ActiveCellDropDownLabel = uilabel(app.GridLayout);
            app.ActiveCellDropDownLabel.Text = 'Active Cell #:';
            app.ActiveCellsDropDown = uidropdown(app.GridLayout);
            app.ActiveCellsDropDown.Items = {'-'};
            app.ActiveCellsDropDown.ValueChangedFcn = createCallbackFcn(app, @ActiveCellsDropDownValueChanged, true);
            app.ActiveCellsDropDown.Value = '-';
            app.InactiveCellDropDownLabel = uilabel(app.GridLayout);
            app.InactiveCellDropDownLabel.Text = 'Inactive Cell #:';
            app.InactiveCellsDropDown = uidropdown(app.GridLayout);
            app.InactiveCellsDropDown.Items = {'-'};
            app.InactiveCellsDropDown.ValueChangedFcn = createCallbackFcn(app, @InactiveCellsDropDownValueChanged, true);
            app.InactiveCellsDropDown.Value = '-';
            app.AccuracyTab = uitab(app.MainTabGroup);
            app.AccuracyTab.Title = 'Manual Accuracy Check';
            app.accuracyGrid = uigridlayout(app.AccuracyTab);
            app.ValidationSignalAxes = uiaxes(app.accuracyGrid);
            xlabel(app.ValidationSignalAxes, 'Time (s)')
            ylabel(app.ValidationSignalAxes, 'dF/F')
            zlabel(app.ValidationSignalAxes, 'Z')
            app.TransientErrorHistogramAxes = uiaxes(app.accuracyGrid);
            title(app.TransientErrorHistogramAxes, 'Error Distribution (NeuroSpikeX v. Manual)')
            xlabel(app.TransientErrorHistogramAxes, 'Error in Peak Count')
            ylabel(app.TransientErrorHistogramAxes, 'Frequency')
            zlabel(app.TransientErrorHistogramAxes, 'Z')
            app.TransientErrorHistogramAxes.Visible = 'off';
            app.LoadDataButtonAccuracy = uibutton(app.accuracyGrid, 'push');
            app.LoadDataButtonAccuracy.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonAccuracyPushed, true);
            app.LoadDataButtonAccuracy.Text = 'Load/Verify Data';
            app.RootFolderLabelAccuracy = uilabel(app.accuracyGrid);
            app.RootFolderLabelAccuracy.Text = 'Sample Root Folder: Not Loaded';
            app.ValidationModeLabel = uilabel(app.accuracyGrid);
            app.ValidationModeLabel.Text = 'Validation Mode:';
            app.Label_2 = uilabel(app.accuracyGrid);
            app.Label_2.Text = '';
            app.ValidationModeDropDown = uidropdown(app.accuracyGrid);
            app.ValidationModeDropDown.Items = {'Cell Active/Inactive', 'Detailed Transient Count'};
            app.ValidationModeDropDown.ItemsData = {'cell', 'transient'};
            app.ValidationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ValidationModeDropDownValueChanged, true);
            app.ValidationModeDropDown.Value = 'cell';
            app.PercentageLabel = uilabel(app.accuracyGrid);
            app.PercentageLabel.Text = '% of cells to review:';
            app.Label_3 = uilabel(app.accuracyGrid);
            app.Label_3.Text = '';
            app.PercentageEditField = uieditfield(app.accuracyGrid, 'numeric');
            app.PercentageEditField.ValueDisplayFormat = '%.0f';
            app.RecommendedPercentageLabel = uilabel(app.accuracyGrid);
            app.RecommendedPercentageLabel.Text = 'Recommended: 10-20% for robust validation (or as per publication standards)';
            app.StartValidationButton = uibutton(app.accuracyGrid, 'push');
            app.StartValidationButton.ButtonPushedFcn = createCallbackFcn(app, @StartValidationButtonPushed, true);
            app.StartValidationButton.Text = 'Start Manual Validation';
            app.ValidationProgressLabel = uilabel(app.accuracyGrid);
            app.ValidationProgressLabel.Text = 'Status: Idle';
            app.TransientCountLabel = uilabel(app.accuracyGrid);
            app.TransientCountLabel.Visible = 'off';
            app.TransientCountLabel.Text = 'Manual Transient Count:';
            app.Label_4 = uilabel(app.accuracyGrid);
            app.Label_4.Text = '';
            app.TransientCountEditField = uieditfield(app.accuracyGrid, 'numeric');
            app.TransientCountEditField.ValueDisplayFormat = '%.0f';
            app.TransientCountEditField.Visible = 'off';
            app.SubmitTransientCountButton = uibutton(app.accuracyGrid, 'push');
            app.SubmitTransientCountButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTransientCountButtonPushed, true);
            app.SubmitTransientCountButton.Visible = 'off';
            app.SubmitTransientCountButton.Text = 'Submit Count & Next';
            app.AccuracyMetricsLabel = uilabel(app.accuracyGrid);
            app.AccuracyMetricsLabel.Text = 'Accuracy Metrics:';
            app.AccuracyResultsTable = uitable(app.accuracyGrid);
            app.AccuracyResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AccuracyResultsTable.RowName = {};
            app.TransientCountMetricsLabel = uilabel(app.accuracyGrid);
            app.TransientCountMetricsLabel.Visible = 'off';
            app.TransientCountMetricsLabel.Text = 'Transient Count Metrics:';
            app.TransientCountResultsTable = uitable(app.accuracyGrid);
            app.TransientCountResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TransientCountResultsTable.RowName = {};
            app.TransientCountResultsTable.Visible = 'off';
            app.IsSignalButton = uibutton(app.accuracyGrid, 'push');
            app.IsSignalButton.ButtonPushedFcn = createCallbackFcn(app, @IsSignalButtonPushed, true);
            app.IsSignalButton.Text = 'Contains Signal (S)';
            app.IsNoiseButton = uibutton(app.accuracyGrid, 'push');
            app.IsNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @IsNoiseButtonPushed, true);
            app.IsNoiseButton.Text = 'Is Noise (N)';
            % Show the figure after all components are created
            app.NeuroSpikeXPostProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PostProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroSpikeXPostProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroSpikeXPostProcessingUIFigure)
        end
    end
end