classdef RelativeBaselineChangeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroSpikeXRBICUIFigure         matlab.ui.Figure
        LogTextArea                     matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        FrameSelectionTab               matlab.ui.container.Tab
        FrameSelectionGrid              matlab.ui.container.GridLayout
        InstructionsLabel               matlab.ui.control.Label
        Timepoint2EditField             matlab.ui.control.EditField
        Timepoint2EditFieldLabel        matlab.ui.control.Label
        Timepoint1EditField             matlab.ui.control.EditField
        Timepoint1EditFieldLabel        matlab.ui.control.Label
        SelectAndLoadButton             matlab.ui.control.Button
        Timepoint1Axes                  matlab.ui.control.UIAxes
        Timepoint2Axes                  matlab.ui.control.UIAxes
        AnalysisSettingsoptionalTab     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        RecalculateRadiiCheckBox        matlab.ui.control.CheckBox
        ApplyManualButton               matlab.ui.control.Button
        ManualInnerEditField            matlab.ui.control.NumericEditField
        InnerRadiusLabel                matlab.ui.control.Label
        ManualOuterEditField            matlab.ui.control.NumericEditField
        ManualOuterFactorLabel          matlab.ui.control.Label
        ManualMethodDropDown            matlab.ui.control.DropDown
        ManualBackgroundSubtractionMethodLabel  matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        VisualizeFailedCellsButton      matlab.ui.control.Button
        SettingsResultsTable            matlab.ui.control.Table
        ApplyandSaveButton              matlab.ui.control.Button
        SettingsInstructionsLabel       matlab.ui.control.Label
        FindOptimalSettingsButton       matlab.ui.control.Button
        AnalysisPipelineTab             matlab.ui.container.Tab
        AnalysisGrid                    matlab.ui.container.GridLayout
        AnalysisModeGroup               matlab.ui.container.ButtonGroup
        ExperimentalModeButton          matlab.ui.control.RadioButton
        ControlModeButton               matlab.ui.control.RadioButton
        ExperimentalAnalysisPanel       matlab.ui.container.Panel
        ExperimentalAnalysisGrid        matlab.ui.container.GridLayout
        ExpTimepoint2SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint2SubfolderNameEditField_2Label  matlab.ui.control.Label
        ExpTimepoint1SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint1SubfolderNameEditField_2Label  matlab.ui.control.Label
        ControlThresholdsField          matlab.ui.control.EditField
        ExperimentalFolderField         matlab.ui.control.EditField
        RunExperimentalButton           matlab.ui.control.Button
        LoadControlThresholdsButton     matlab.ui.control.Button
        SelectExperimentalFolderButton  matlab.ui.control.Button
        ExperimentalDistributionAxes    matlab.ui.control.UIAxes
        ExperimentalSpatialAxes         matlab.ui.control.UIAxes
        ControlAnalysisPanel            matlab.ui.container.Panel
        ControlAnalysisGrid             matlab.ui.container.GridLayout
        Timepoint1SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint1SubfolderNameEditFieldLabel  matlab.ui.control.Label
        Timepoint2SubfolderNameEditField  matlab.ui.control.EditField
        Timepoint2SubfolderNameEditFieldLabel  matlab.ui.control.Label
        RunControlButton                matlab.ui.control.Button
        ControlsListBox                 matlab.ui.control.ListBox
        ControlsListBoxLabel            matlab.ui.control.Label
        SelectControlFoldersButton      matlab.ui.control.Button
        ControlsDistributionAxes        matlab.ui.control.UIAxes
        ControlSpatialExampleAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Properties for Tab 1
        FolderPath1          % Path to the first timepoint folder
        FolderPath2          % Path to the second timepoint folder
        Folder1Name_short    % Just the name of the folder (e.g., '0minus')
        Folder2Name_short    % Just the name of the folder (e.g., '0plus')
        FData1
        FData2
        CursorLine1 % Handle for the line on the left axes
        CursorLine2 % Handle for the line on the right axes
        SelectionState char % Will be 'left', 'right', or inactive
        FrameBefore % Store the frame number from the first click

        % Properties for Settings Tab 2
        AnalysisSettings struct
        OptimizationResults table
        TempMatchedData struct

        % Properties for Tab 3
        ControlFolderPaths cell
        ExperimentalFolderPath
        ThresholdData struct
    end
    
    methods (Access = private)
        
        function startupFcn(app)

            logMessages = {'Welcome!'};
            
            app.AnalysisModeGroup.SelectedObject = app.ExperimentalModeButton;
            
            defaultSettings = struct(...
                'Method', 'Median', 'InnerFactor', 1.8, 'OuterFactor', 2.0, 'RecalculateRadii', false);
            app.AnalysisSettings = defaultSettings;
            
            settingsFile = fullfile(app.getAppPath(), 'CalciumBaselineAnalysisSettings.mat');
            if exist(settingsFile, 'file')
                try
                    loadedVars = load(settingsFile); 
                    if isfield(loadedVars, 'savedSettings') && isstruct(loadedVars.savedSettings)
                        sourceStruct = loadedVars.savedSettings;
                        logMessages{end+1} = 'Found ''savedSettings'' struct. Loading...';
                    else
                        sourceStruct = loadedVars;
                        logMessages{end+1} = 'No ''savedSettings'' struct found. Checking legacy format...';
                    end
                    if isfield(sourceStruct, 'Method'), app.AnalysisSettings.Method = sourceStruct.Method; end
                    if isfield(sourceStruct, 'InnerFactor'), app.AnalysisSettings.InnerFactor = sourceStruct.InnerFactor; end
                    if isfield(sourceStruct, 'OuterFactor'), app.AnalysisSettings.OuterFactor = sourceStruct.OuterFactor; end
                    if isfield(sourceStruct, 'RecalculateRadii'), app.AnalysisSettings.RecalculateRadii = sourceStruct.RecalculateRadii; end
                    logMessages{end+1} = 'Settings successfully updated from file.';
                catch ME
                    logMessages{end+1} = ['Warning: Could not load settings file. Using defaults. Error: ' ME.message];
                end
            else
                logMessages{end+1} = 'No settings file found. Using default settings.';
            end
        
            logMessages{end+1} = sprintf(...
                'Current settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ... 
                app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, ...
                app.AnalysisSettings.OuterFactor, app.AnalysisSettings.RecalculateRadii);

            % Set the value. The font is already correct.
            app.LogTextArea.Value = logMessages;
        end
        
        function outputFolderPath = createOutputFolder(app, sampleFolderPath)
            % This function creates a dedicated output folder for a given sample.
            % e.g., if sampleFolderPath is 'C:\Data\Sample1', it creates and
            % returns 'C:\Data\Sample1\BaselineChange'.
            
            outputFolderName = 'BaselineChange';
            outputFolderPath = fullfile(sampleFolderPath, outputFolderName);
            
            % Check if the folder already exists, if not, create it.
            if ~exist(outputFolderPath, 'dir')
                mkdir(outputFolderPath);
                app.LogTextArea.Value{end+1} = ['    Created output folder: ' outputFolderName];
            end
        end

        function app_path = getAppPath(app)
            % This function finds the directory where the .mlapp file is located.
            try
                full_path = mfilename('fullpath');
                % fileparts will return the path to the folder containing the file
                app_path = fileparts(full_path);
            catch
                % Fallback to the current working directory if something goes wrong
                app_path = pwd;
            end
        end

        function resultsTable = optimizeBackgroundSettings(app, image1, image2, centroids1, radii1, centroids2, radii2, bgMethod)
            %optimizeBackgroundSettings Iterates through settings to find optimal parameters
            
            % --- 1. Define the Search Space ---
            innerFactors = 1.2:0.1:3.0;
            outerFactorSteps = 0.2:0.1:1.5;
            combinations = [];
            for inner = innerFactors
                for step = outerFactorSteps
                    outer = inner + step;
                    combinations = [combinations; inner, outer];
                end
            end
            numCombinations = size(combinations, 1);
            
            % --- 2. Create local copies for parallel processing ---
            % The radii passed in here are now the FINAL ones to be used.
            local_image1 = image1;
            local_image2 = image2;
            local_centroids1 = centroids1;
            local_centroids2 = centroids2;
            local_radii1 = radii1;
            local_radii2 = radii2;
            
            % Pre-allocate results arrays
            qualityScores = zeros(numCombinations, 1);
            innerFactors = zeros(numCombinations, 1);
            outerFactors = zeros(numCombinations, 1);
            methods = cell(numCombinations, 1);
            validCounts = zeros(numCombinations, 1);
            
            % --- 3. Run Optimization Loop ---
            parfor i = 1:numCombinations
                bgInnerFactor = combinations(i, 1);
                bgOuterFactor = combinations(i, 2);
                
                numCells = size(local_centroids1, 1);
                intensity1 = zeros(numCells, 1);
                intensity2 = zeros(numCells, 1);
                
                for c = 1:numCells
                    x = local_centroids1(c,1); 
                    y = local_centroids1(c,2); 
                    r = local_radii1(c); % Directly use the provided radius
                    
                    x2 = local_centroids2(c,1);  
                    y2 = local_centroids2(c,2);  
                    r2 = local_radii2(c); % Directly use the provided radius
                    
                    % --- The logic for recalculation is GONE from here ---
                    % This makes the loop much cleaner and faster.
                    
                    [intensity1_cell, ~] = calculateSingleCellIntensity(...
                        local_image1, x, y, r, bgInnerFactor, bgOuterFactor, bgMethod);
                    
                    [intensity2_cell, ~] = calculateSingleCellIntensity(...
                        local_image2, x2, y2, r2, bgInnerFactor, bgOuterFactor, bgMethod);
        
                    intensity1(c) = intensity1_cell;
                    intensity2(c) = intensity2_cell;
                end
                
                valid_cells = (intensity1 > 0) & (intensity2 > 0) & ~isnan(intensity1) & ~isnan(intensity2);
                validCount = sum(valid_cells);
                qualityScore = (validCount / numCells) * 100;
                
                innerFactors(i) = bgInnerFactor;
                outerFactors(i) = bgOuterFactor;
                methods{i} = bgMethod;
                qualityScores(i) = qualityScore;
                validCounts(i) = validCount;
            end
            
            if numCombinations >= 1
                app.LogTextArea.Value{end+1} = sprintf(...
                    '[Optimization] Combo 1 (%.1f,%.1f): %d/%d cells valid at BOTH timepoints', ...
                    combinations(1,1), combinations(1,2), validCounts(1), size(local_centroids1, 1));
            end
            
            resultsTable = table(...
                innerFactors, outerFactors, methods, qualityScores, ...
                'VariableNames', {'InnerFactor', 'OuterFactor', 'Method', 'QualityScore_percent'});
            
            resultsTable = sortrows(resultsTable, 'QualityScore_percent', 'descend');
        end

        function [IntensityChange, radii_final_1, radii_final_2] = calculateIntensityChange(app, MatchedCells)
            % Initialize default outputs in case of early exit
            IntensityChange = struct();
            radii_final_1 = [];
            radii_final_2 = [];
            
            try
                % --- UNPACK DATA AND SETTINGS ---
                app.LogTextArea.Value{end+1} = '--- INTENSITY CALCULATION DIAGNOSTICS ---';
                matchedCentroids1 = MatchedCells.matchedCentroidCoordinates_0minus;
                matchedRadii1 = MatchedCells.matchedRadii_0minus;
                matchedCentroids2 = MatchedCells.matchedCentroidCoordinates_timepoint2;
                matchedRadii2 = MatchedCells.matchedRadii_timepoint2;
                image1_raw = MatchedCells.GCaMP_Image1;
                image2_raw = MatchedCells.GCaMP_Image2;
                
                settings = app.AnalysisSettings;
                recalculateRadii = settings.RecalculateRadii;
                numMatched = size(matchedCentroids1, 1);

                app.LogTextArea.Value{end+1} = sprintf('Matched cells: %d. Settings: Method=%s, Inner=%.2f, Outer=%.2f, Recalc=%d', ...
                    numMatched, settings.Method, settings.InnerFactor, settings.OuterFactor, recalculateRadii);
                
                se = strel('disk', 15);
                image1 = imtophat(im2single(image1_raw), se);
                image2 = imtophat(im2single(image2_raw), se);
                
                intensity1 = zeros(numMatched, 1, 'single');
                intensity2 = zeros(numMatched, 1, 'single');
                radii_final_1 = zeros(numMatched, 1);
                radii_final_2 = zeros(numMatched, 1);
        
                % --- RADIUS & INTENSITY CALCULATION LOOP ---
                for i = 1:numMatched
                    r1_orig = matchedRadii1(i); r2_orig = matchedRadii2(i);
                    r1_final = r1_orig; r2_final = r2_orig;
                    if recalculateRadii
                        x1 = round(matchedCentroids1(i,1)); y1 = round(matchedCentroids1(i,2));
                        patch_half_width = ceil(r1_orig * 2.5);
                        ymin = max(1, y1 - patch_half_width); ymax = min(size(image1,1), y1 + patch_half_width);
                        xmin = max(1, x1 - patch_half_width); xmax = min(size(image1,2), x1 + patch_half_width);
                        patch1 = image1(ymin:ymax, xmin:xmax);
                        if ~isempty(patch1), r1_final = findBaselineRadius(patch1, r1_orig); end
                        
                        x2 = round(matchedCentroids2(i,1)); y2 = round(matchedCentroids2(i,2));
                        patch_half_width2 = ceil(r2_orig * 2.5);
                        ymin2 = max(1, y2 - patch_half_width2); ymax2 = min(size(image2,1), y2 + patch_half_width2);
                        xmin2 = max(1, x2 - patch_half_width2); xmax2 = min(size(image2,2), x2 + patch_half_width2);
                        patch2 = image2(ymin2:ymax2, xmin2:xmax2);
                        if ~isempty(patch2), r2_final = findBaselineRadius(patch2, r2_orig); end
                    end
                    radii_final_1(i) = r1_final; radii_final_2(i) = r2_final;
                    [intensity1(i), ~] = calculateSingleCellIntensity(image1, matchedCentroids1(i,1), matchedCentroids1(i,2), r1_final, settings.InnerFactor, settings.OuterFactor, settings.Method);
                    [intensity2(i), ~] = calculateSingleCellIntensity(image2, matchedCentroids2(i,1), matchedCentroids2(i,2), r2_final, settings.InnerFactor, settings.OuterFactor, settings.Method);
                end
                
                % --- VALIDATION & FOLD CHANGE ---
                
                % A measurement is truly invalid if it's negative or NaN. A zero is a valid measurement.
                invalid_measurement_T1 = (intensity1 < 0) | isnan(intensity1);
                invalid_measurement_T2 = (intensity2 < 0) | isnan(intensity2);
                combined_invalid_mask = invalid_measurement_T1 | invalid_measurement_T2;
                
                num_valid_measurements = numMatched - sum(combined_invalid_mask);
                app.LogTextArea.Value{end+1} = sprintf('    Invalid measurements [Negative or NaN]: T1=%d, T2=%d. Total valid cells: %d/%d (%.1f%%)', ...
                    sum(invalid_measurement_T1), sum(invalid_measurement_T2), num_valid_measurements, numMatched, (num_valid_measurements/numMatched)*100);

                % Use a stable pseudocount for regularization. This correctly handles intensity = 0.
                pseudocount = 1e-6; 
                intensity1_reg = intensity1 + pseudocount;
                intensity2_reg = intensity2 + pseudocount;
                
                cell_FoldChange = intensity2_reg ./ intensity1_reg; % Placeholder, will be overwritten
                cell_logFoldChange = log2(intensity2_reg ./ intensity1_reg);
                
                % Set logFC to NaN ONLY for cells with genuinely invalid measurements.
                cell_logFoldChange(combined_invalid_mask) = NaN;
                
                % We also need to get the fold change for the invalid mask
                cell_FoldChange(combined_invalid_mask) = NaN;
                
                IntensityChange = struct(...
                    'cell_FoldChange', cell_FoldChange, ...
                    'cell_logFoldChange', cell_logFoldChange, ...
                    'mean_logFoldChange', mean(cell_logFoldChange, 'omitnan'), ...
                    'image1_for_plotting', image1, ...
                    'centroids1_for_plotting', matchedCentroids1, ...
                    'invalidMask_for_plotting', combined_invalid_mask);
                
            catch ME
                errMsg = sprintf('Error in intensity calculation:\n%s', getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.NeuroSpikeXRBICUIFigure, errMsg, 'Critical Calculation Error');
                IntensityChange = struct();
            end
        end
           
        function createExcludedCellsFigure(app, fig, image, centroids, invalidMask)
            % This function now populates a PRE-EXISTING figure handle
            % It MUST accept 'fig' as the first argument after 'app'
            ax = axes(fig);
            imshow(image, 'Parent', ax);
            hold(ax, 'on');
            
            % Plot valid cells in green
            validIdx = find(~invalidMask);
            if ~isempty(validIdx)
                scatter(ax, centroids(validIdx,1), centroids(validIdx,2), ...
                    'g', 'filled', 'MarkerFaceAlpha', 0.5);
            end
            
            % Plot invalid cells in red
            invalidIdx = find(invalidMask);
            if ~isempty(invalidIdx)
                scatter(ax, centroids(invalidIdx,1), centroids(invalidIdx,2), ...
                    'r', 'filled', 'MarkerFaceAlpha', 0.5);
            end
            
            title(ax, 'Spatial Distribution of Excluded Cells');
            legend(ax, 'Valid T1', 'Excluded', 'Location', 'best');
            hold(ax, 'off');
        end

        % HELPER 1: Handles mouse movement. This version is correct.
        function updateCursorLines(app)
            % Corrected axes names
            axes_to_check = [app.Timepoint1Axes, app.Timepoint2Axes];
            fig_pt = app.NeuroSpikeXRBICUIFigure.CurrentPoint;
            is_over_an_axis = false;
            for ax = axes_to_check
                ax_pos = getpixelposition(ax);
                is_inside = fig_pt(1) >= ax_pos(1) && fig_pt(1) <= ax_pos(1) + ax_pos(3) && ...
                            fig_pt(2) >= ax_pos(2) && fig_pt(2) <= ax_pos(2) + ax_pos(4);
                if is_inside
                    is_over_an_axis = true;
                    x_coord = ax.CurrentPoint(1, 1);
                    if isvalid(app.CursorLine1); set(app.CursorLine1, 'XData', [x_coord, x_coord]); end
                    if isvalid(app.CursorLine2); set(app.CursorLine2, 'XData', [x_coord, x_coord]); end
                    break; 
                end
            end
            if ~is_over_an_axis
                if isvalid(app.CursorLine1); set(app.CursorLine1, 'XData', [NaN, NaN]); end
                if isvalid(app.CursorLine2); set(app.CursorLine2, 'XData', [NaN, NaN]); end
            end
        end
    
        % HELPER 2: Handles mouse clicks.
        function processClick(app)
            if strcmp(app.SelectionState, 'inactive')
                % Explicitly remove cursor lines
                delete(app.CursorLine1);
                delete(app.CursorLine2);
                return; 
            end

            if strcmp(app.SelectionState, 'inactive'); return; end
            
            target_ax = [];
            if strcmp(app.SelectionState, 'left')
                target_ax = app.Timepoint1Axes; % Corrected name
            elseif strcmp(app.SelectionState, 'right')
                target_ax = app.Timepoint2Axes; % Corrected name
            end
            
            if isempty(target_ax); return; end % Safety check
    
            ax_pos = getpixelposition(target_ax);
            fig_pt = app.NeuroSpikeXRBICUIFigure.CurrentPoint;
            if ~(fig_pt(1) >= ax_pos(1) && fig_pt(1) <= ax_pos(1) + ax_pos(3) && ...
                 fig_pt(2) >= ax_pos(2) && fig_pt(2) <= ax_pos(2) + ax_pos(4));
                return;
            end
            
            if strcmp(app.SelectionState, 'left')
                x_axis1 = app.FData1(1, :);
                xsel1 = target_ax.CurrentPoint(1, 1);
                [~, frame_idx] = min(abs(x_axis1 - xsel1));
                app.FrameBefore = frame_idx;
                
                hold(target_ax, 'on');
                xline(target_ax, x_axis1(frame_idx), 'r--', 'LineWidth', 1.5, 'Label', ['F ' num2str(frame_idx)]);
                hold(target_ax, 'off');
                
                app.SelectionState = 'right';
                app.LogTextArea.Value = {'Left frame selected. Now click on the RIGHT plot.'};
                app.InstructionsLabel.Text = 'INSTRUCTIONS: Click on the RIGHT plot to select a frame.'; % Corrected name
                
            elseif strcmp(app.SelectionState, 'right')
                x_axis2 = app.FData2(1, :);
                xsel2 = target_ax.CurrentPoint(1, 1);
                [~, frame_after] = min(abs(x_axis2 - xsel2));
                
                hold(target_ax, 'on');
                xline(target_ax, x_axis2(frame_after), 'r--', 'LineWidth', 1.5, 'Label', ['F ' num2str(frame_after)]);
                hold(target_ax, 'off');
                
                app.SelectionState = 'inactive';
                app.NeuroSpikeXRBICUIFigure.WindowButtonMotionFcn = '';
                app.NeuroSpikeXRBICUIFigure.WindowButtonDownFcn = '';
                delete(app.CursorLine1);
                delete(app.CursorLine2);
    
                frame_before = app.FrameBefore;
                msg = sprintf(['ACTION REQUIRED:\n\n' ...
                               '1. Save frame %d as "beforeraw.tif" in folder:\n%s\n\n' ...
                               '2. Save frame %d as "afterraw.tif" in folder:\n%s\n\n' ...
                               'Once images are saved, proceed to next tab.'], ...
                               frame_before, app.FolderPath1, frame_after, app.FolderPath2);
                app.InstructionsLabel.Text = msg; % Corrected name
                app.LogTextArea.Value = {'Frame selection complete.'};
            end
        end
        
        function plotSpatialDistribution(app, target_axes, MatchedCells, IntensityChange, threshold_high, threshold_low)
            cla(target_axes);
            
            % --- Get data for plotting ---
            matchedCentroids = MatchedCells.matchedCentroidCoordinates_0minus;
            isIncrease = IntensityChange.cell_logFoldChange > threshold_high;
            isDecrease = IntensityChange.cell_logFoldChange < threshold_low;
            isNoChange = ~isIncrease & ~isDecrease;

            % --- KEY CHANGE: ADVANCED CONTRAST ENHANCEMENT ---
            % 1. Get the original image.
            base_image = im2gray(im2single(MatchedCells.GCaMP_Image1));
            
            % 2. Isolate the bright cell peaks using a top-hat filter.
            %    This creates an image of just the cells on a black background.
            se = strel('disk', 15); % A disk structuring element, 15 is a good default
            tophat_image = imtophat(base_image, se);
            
            % 3. Add the isolated cell brightness back to the original image.
            %    This selectively makes the cells "glow" without brightening background noise.
            %    We can even add a multiplier to the top-hat image to increase the effect.
            enhancement_factor = 1.5; % Tune this value (e.g., 1.0, 1.5, 2.0)
            bright_image = base_image + (tophat_image * enhancement_factor);
            
            % --- Create the plot ---
            imshow(bright_image, 'Parent', target_axes);
            hold(target_axes, 'on');
            
            % --- Scatter plot with separate transparency for fill and outline ---
            scatter(target_axes, matchedCentroids(isIncrease,1), matchedCentroids(isIncrease,2), 35, 'o', 'MarkerFaceColor', '#1F77B4', 'MarkerEdgeColor', '#1F77B4', 'MarkerFaceAlpha', 0.5, 'MarkerEdgeAlpha', 0.8);
            scatter(target_axes, matchedCentroids(isNoChange,1), matchedCentroids(isNoChange,2), 35, 'o', 'MarkerFaceColor', '#FFFF00', 'MarkerEdgeColor', '#FFFF00', 'MarkerFaceAlpha', 0.4, 'MarkerEdgeAlpha', 0.7);
            scatter(target_axes, matchedCentroids(isDecrease,1), matchedCentroids(isDecrease,2), 35, 'o', 'MarkerFaceColor', '#D62728', 'MarkerEdgeColor', '#D62728', 'MarkerFaceAlpha', 0.5, 'MarkerEdgeAlpha', 0.8);

            hold(target_axes, 'off');
            
            % --- Configure the Axes Appearance ---
            set(target_axes, 'Visible', 'on', 'XColor', 'none', 'YColor', 'none', 'Color', 'none', ...
                'XTick', [], 'YTick', [], 'Box', 'off');

            % Configure the title and legend
            title(target_axes, 'Spatial Distribution of Change', 'Color', 'k', 'BackgroundColor', [1 1 1 0.7], 'FontSize', 10);
            lgd = legend(target_axes, ['Increase (',num2str(sum(isIncrease)),')'], ['No Change (',num2str(sum(isNoChange)),')'], ['Decrease (',num2str(sum(isDecrease)),')']);
            set(lgd, 'Location', 'northoutside', 'Orientation', 'horizontal', 'Color', 'w', ...
                'TextColor', 'k', 'FontSize', 8, 'Box', 'on');
        end
        
        function plotChangeHistogram(app, target_axes, logFoldChangeData, threshold_high, threshold_low)
            % This version uses a robust plotting order to ensure all elements are saved correctly.
            
            % 1. Start with a clean slate.
            cla(target_axes);
            
            % 2. Validate the data first.
            validData = logFoldChangeData(~isnan(logFoldChangeData));
            
            if numel(validData) < 2
                % If there's no data, just put a message and title.
                text(target_axes, 0.5, 0.5, 'Not enough data for histogram.', ...
                    'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                title(target_axes, 'Distribution of log2(Intensity2/Intensity1)');
                return; 
            end
            
            % --- ROBUST PLOTTING ORDER ---
            % 3. Plot the primary data FIRST. This is the command that
            %    fully initializes and activates the axes object.
            histogram(target_axes, validData, 'BinMethod', 'auto', 'Normalization', 'pdf', 'FaceColor', [0.5 0.5 0.5]);
            
            % 4. NOW that the axes are active, apply all decorations.
            hold(target_axes, 'on');
            title(target_axes, 'Distribution of log2(Fold Change)');
            xlabel(target_axes, 'log2(Intensity2/Intensity1)');
            ylabel(target_axes, 'Density');
            
            % 5. Add any overlays, like the threshold lines.
            if ~isempty(threshold_high) && ~isempty(threshold_low)
                xline(target_axes, threshold_low, '--r', {'Control 2.5%'}, 'LineWidth', 1.5);
                xline(target_axes, threshold_high, '--r', {'Control 97.5%'}, 'LineWidth', 1.5);
            end
        
            % 6. Release the hold.
            hold(target_axes, 'off');
        end

        function scrollLogToBottom(app)
            % This function scrolls the LogTextArea to the bottom.
            % It uses the modern 'scroll' function, which is the correct
            % method for web-based uifigures in App Designer.
            try
                % Ensure all pending UI updates are processed first
                drawnow; 
                
                % Scroll the text area to the bottom
                scroll(app.LogTextArea, 'bottom');
                
            catch ME
                % This catch block handles cases where the 'scroll' function
                % might not be available (older MATLAB versions) or if another
                % error occurs. It prevents the app from crashing.
                fprintf(2, 'Warning: Auto-scrolling of the log area failed. Error: %s\n', ME.message);
            end
        end

        function saveFigure(app, figHandle, baseFilePath)
            % This robustly saves a figure by moving it off-screen, making
            % it visible to force a full render, and then saving.
            % NOTE: THIS FUNCTION DOES NOT CLOSE THE FIGURE HANDLE.
            
            % --- The Off-Screen Rendering Trick ---
            screen_size = get(groot, 'ScreenSize');
            set(figHandle, 'Position', [screen_size(3)+50, screen_size(4)+50, 560, 420]);
            set(figHandle, 'Visible', 'on');
            drawnow;

            % --- Standard Saving Logic ---
            pdf_path = [baseFilePath '.pdf'];
            fig_path = [baseFilePath '.fig'];
            set(figHandle, 'PaperPositionMode', 'auto');
            
            try
                print(figHandle, pdf_path, '-dpdf', '-vector', '-bestfit');
                app.LogTextArea.Value{end+1} = ['  -> Saved editable PDF: ' pdf_path];
            catch ME
                app.LogTextArea.Value{end+1} = ['  -> Warning: Could not save PDF. Error: ' ME.message];
            end

            try
                savefig(figHandle, fig_path, '-v7.3');
                app.LogTextArea.Value{end+1} = ['  -> Saved .fig: ' fig_path];
                app.scrollLogToBottom();
            catch ME
                app.LogTextArea.Value{end+1} = ['  -> Warning: Could not save .fig file. Error: ' ME.message];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAndLoadButton
        function SelectAndLoadButtonPushed(app, event)
            folderpath1 = uigetdir(pwd, '*** STEP 1: Select the folder for the FIRST timepoint ***');
            if folderpath1 == 0; return; end
            app.FolderPath1 = folderpath1;
            app.Timepoint1EditField.Value = folderpath1; % Corrected name
            [~, name1, ~] = fileparts(folderpath1);
            app.Folder1Name_short = name1;
            drawnow;
            folderpath2 = uigetdir(pwd, '*** STEP 2: Select the folder for the SECOND timepoint ***');
            if folderpath2 == 0; app.LogTextArea.Value = {'Canceled.'}; app.FolderPath1 = []; app.Timepoint1EditField.Value = ''; return; end % Corrected name
            app.FolderPath2 = folderpath2;
            app.Timepoint2EditField.Value = folderpath2; % Corrected name
            [~, name2, ~] = fileparts(folderpath2);
            app.Folder2Name_short = name2;
            drawnow;
            try
                app.FData1 = importdata(fullfile(app.FolderPath1, 'fdata.mat'));
                app.FData2 = importdata(fullfile(app.FolderPath2, 'fdata.mat'));
            catch ME; uialert(app.NeuroSpikeXRBICUIFigure, ['Load Error: ' ME.message], 'Error'); return; end
            if isempty(app.FData1) || isempty(app.FData2); uialert(app.NeuroSpikeXRBICUIFigure, 'Data is empty.', 'Data Error'); return; end
        
            cla(app.Timepoint1Axes); hold(app.Timepoint1Axes, 'on'); title(app.Timepoint1Axes, ['Timepoint 1: ' app.Folder1Name_short]); xlabel(app.Timepoint1Axes, 'Time (s)'); ylabel(app.Timepoint1Axes, 'dF/F');
            x_axis1 = app.FData1(1, :); num_signals1 = size(app.FData1, 1) - 1; num_to_plot1 = min(5, num_signals1);
            if num_to_plot1 > 0; rows_to_plot1 = randperm(num_signals1, num_to_plot1) + 1; plot(app.Timepoint1Axes, x_axis1, app.FData1(rows_to_plot1, :)); end
            hold(app.Timepoint1Axes, 'off');
            cla(app.Timepoint2Axes); hold(app.Timepoint2Axes, 'on'); title(app.Timepoint2Axes, ['Timepoint 2: ' app.Folder2Name_short]); xlabel(app.Timepoint2Axes, 'Time (s)'); ylabel(app.Timepoint2Axes, 'dF/F');
            x_axis2 = app.FData2(1, :); num_signals2 = size(app.FData2, 1) - 1; num_to_plot2 = min(5, num_signals2);
            if num_to_plot2 > 0; rows_to_plot2 = randperm(num_signals2, num_to_plot2) + 1; plot(app.Timepoint2Axes, x_axis2, app.FData2(rows_to_plot2, :)); end
            hold(app.Timepoint2Axes, 'off');
            if num_to_plot1 == 0 || num_to_plot2 == 0; uialert(app.NeuroSpikeXRBICUIFigure, 'No signals in data.', 'Data Error'); return; end
        
            app.CursorLine1 = line(app.Timepoint1Axes, [NaN NaN], get(app.Timepoint1Axes, 'YLim'), 'Color', [0.3 0.3 0.3], 'LineStyle', '--');
            app.CursorLine2 = line(app.Timepoint2Axes, [NaN NaN], get(app.Timepoint2Axes, 'YLim'), 'Color', [0.3 0.3 0.3], 'LineStyle', '--');
            app.SelectionState = 'left';
            app.NeuroSpikeXRBICUIFigure.WindowButtonMotionFcn = @(~,~) app.updateCursorLines();
            app.NeuroSpikeXRBICUIFigure.WindowButtonDownFcn = @(~,~) app.processClick();
            app.LogTextArea.Value = {'Plots loaded. Please click on the LEFT plot to select a frame.'};
            app.InstructionsLabel.Text = 'INSTRUCTIONS: Click on the LEFT plot to select a frame.'; % Corrected name
        end

        % Selection changed function: AnalysisModeGroup
        function AnalysisModeGroupSelectionChanged(app, event)
            selectedButton = app.AnalysisModeGroup.SelectedObject;
            if selectedButton == app.ControlModeButton % Corrected name
                app.ControlAnalysisPanel.Visible = 'on';
                app.ExperimentalAnalysisPanel.Visible = 'off';
            else
                app.ControlAnalysisPanel.Visible = 'off';
                app.ExperimentalAnalysisPanel.Visible = 'on';
            end
        end

        % Button pushed function: SelectControlFoldersButton
        function SelectControlFoldersButtonPushed(app, event)
            app.LogTextArea.Value = {'Starting folder selection...'};
            app.LogTextArea.Value{end+1} = 'Please select your control folders one by one.';
            app.LogTextArea.Value{end+1} = 'Click CANCEL when you are finished.';
            drawnow; 

            all_paths = {};
            last_path = pwd; 
            folder_count = 1; % Initialize the counter

            while true
                % --- START OF MODIFICATION: Add counter to dialog title ---
                dialog_title = sprintf('Select Control Folder #%d (Click Cancel when done)', folder_count);
                folder_path = uigetdir(last_path, dialog_title);
                % --- END OF MODIFICATION ---
                
                if folder_path == 0
                    break; % User clicked Cancel, exit the loop
                end
                
                all_paths{end+1} = folder_path; % Corrected typo: used 'folder_path' instead of 'folderpath'
                last_path = folder_path;
                
                app.LogTextArea.Value{end+1} = sprintf('Added #%d: %s', folder_count, folder_path);
                drawnow;

                folder_count = folder_count + 1; % Increment the counter for the next loop
            end
            
            if ~isempty(all_paths)
                app.LogTextArea.Value{end+1} = '--- Folder selection complete. ---';
                app.ControlFolderPaths = all_paths;
                app.ControlsListBox.Items = all_paths;
                app.LogTextArea.Value{end+1} = [num2str(numel(all_paths)) ' control folders selected. Ready to run analysis.'];
                app.scrollLogToBottom();
            else
                app.LogTextArea.Value{end+1} = 'Folder selection cancelled. No folders were selected.';
                app.scrollLogToBottom();
            end
        end

        % Button pushed function: RunControlButton
        function RunControlButtonPushed(app, event)
            % --- 1. Initial UI Validation & Setup ---
            if isempty(app.ControlFolderPaths), uialert(app.NeuroSpikeXRBICUIFigure, 'Please select control folders first.', 'Error'); return; end
            subfolderName1 = app.Timepoint1SubfolderNameEditField.Value;
            subfolderName2 = app.Timepoint2SubfolderNameEditField.Value;
            if isempty(subfolderName1) || isempty(subfolderName2), uialert(app.NeuroSpikeXRBICUIFigure, 'Please enter subfolder names.', 'Error'); return; end
            if isempty(app.AnalysisSettings), uialert(app.NeuroSpikeXRBICUIFigure, 'Analysis settings are not initialized.', 'Settings Error'); return; end
            
            label1 = app.Timepoint1SubfolderNameEditField.Value;
            label2 = app.Timepoint2SubfolderNameEditField.Value;

            app.LogTextArea.Value = {'Running Control Analysis...'};
            app.LogTextArea.Value{end+1} = sprintf('Using settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ...
                app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor, app.AnalysisSettings.RecalculateRadii);
            app.scrollLogToBottom();
            
            % --- PASS 1: Loop through folders to calculate and collect all data ---
            app.LogTextArea.Value{end+1} = '--- PASS 1: Calculating intensity changes for all samples ---';
            
            numSamples = numel(app.ControlFolderPaths);
            sampleResults = cell(numSamples, 1);
            
            for i = 1:numSamples
                folder = app.ControlFolderPaths{i};
                pathParts = strsplit(folder, filesep);
                sampleName = pathParts{end};
                app.LogTextArea.Value{end+1} = ['Processing: ' sampleName];
                app.scrollLogToBottom();
                
                currentSampleData = struct('Name', sampleName, 'MatchedCells', [], 'IntensityChange', []);
                
                try
                    % --- Create the BaselineChange subfolder for THIS sample ---
                    outputFolder = app.createOutputFolder(folder); 
                    
                    % --- Load or create matched cells data ---
                    MatchedCells = [];
                    matchedFile = fullfile(outputFolder, 'MatchedCells.mat');
                    
                    if exist(matchedFile, 'file')
                        app.LogTextArea.Value{end+1} = '    -> Loading existing MatchedCells.mat...';
                        warning_state = warning('off', 'MATLAB:load:variableNotFound');
                        cleanupObj = onCleanup(@() warning(warning_state));
                        loaded = load(matchedFile, 'MatchedCells');
                        if isfield(loaded, 'MatchedCells')
                            MatchedCells = loaded.MatchedCells;
                        else 
                            app.LogTextArea.Value{end+1} = '    -> "MatchedCells" not found, attempting to find first struct...';
                            loaded_all = load(matchedFile);
                            vars = fieldnames(loaded_all);
                            if ~isempty(vars), MatchedCells = loaded_all.(vars{1}); end
                        end
                    else
                        app.LogTextArea.Value{end+1} = '    -> No MatchedCells.mat found. Please follow prompts for manual cell matching...';
                        drawnow;
                        
                        image1_subfolder = fullfile(filesep, subfolderName1);
                        image2_subfolder = fullfile(filesep, subfolderName2);

                        [MatchedCells, fig_handle_matching] = matchCells(folder, image1_subfolder, image2_subfolder, [], label1, label2);

                        if ishandle(fig_handle_matching)
                            baseFigPath = fullfile(outputFolder, 'diagnostic_cell_matching');
                            app.saveFigure(fig_handle_matching, baseFigPath);
                            close(fig_handle_matching);
                        end
                        app.LogTextArea.Value{end+1} = '    -> Saving new MatchedCells.mat...';
                        save(matchedFile, 'MatchedCells');
                    end
                    
                    if isempty(MatchedCells), app.LogTextArea.Value{end+1} = '    -> Matching failed or was cancelled. Skipping.'; sampleResults{i} = currentSampleData; continue; end
                    
                    [IntensityChange, radii1_final, radii2_final] = app.calculateIntensityChange(MatchedCells);
                    
                    if app.AnalysisSettings.RecalculateRadii && ~isempty(fieldnames(IntensityChange))
                        diagFig = figure('Name', ['Radii Diagnostic: ' sampleName], 'NumberTitle', 'off', 'Visible', 'off');
                        t = tiledlayout(1, 2, 'Padding', 'compact', 'TileSpacing', 'compact');
                        title(t, ['Original (Red) vs. Recalculated (Green) Radii for ' sampleName]);
                        ax1 = nexttile; imshow(MatchedCells.GCaMP_Image1, 'Parent', ax1); hold(ax1, 'on');
                        viscircles(ax1, MatchedCells.matchedCentroidCoordinates_0minus, MatchedCells.matchedRadii_0minus, 'Color', 'r', 'LineStyle', '--');
                        viscircles(ax1, MatchedCells.matchedCentroidCoordinates_0minus, radii1_final, 'Color', 'g');
                        title(ax1, label1); 
                        hold(ax1, 'off');

                        ax2 = nexttile; imshow(MatchedCells.GCaMP_Image2, 'Parent', ax2); hold(ax2, 'on');
                        viscircles(ax2, MatchedCells.matchedCentroidCoordinates_timepoint2, MatchedCells.matchedRadii_timepoint2, 'Color', 'r', 'LineStyle', '--');
                        viscircles(ax2, MatchedCells.matchedCentroidCoordinates_timepoint2, radii2_final, 'Color', 'g');
                        title(ax2, label2); 
                        hold(ax2, 'off');
                        baseFigPath = fullfile(outputFolder, 'diagnostic_recalculated_radii');
                        app.saveFigure(diagFig, baseFigPath);
                        close(diagFig);
                    end
                    
                    currentSampleData.MatchedCells = MatchedCells;
                    currentSampleData.IntensityChange = IntensityChange;
                    sampleResults{i} = currentSampleData;
                    
                catch ME
                    app.LogTextArea.Value{end+1} = ['!!! ERROR processing ' sampleName ': ' getReport(ME, 'basic')];
                    disp(ME.getReport);
                    sampleResults{i} = currentSampleData; 
                    app.scrollLogToBottom();
                    continue;
                end
            end
            
            % --- PASS 2: Aggregating data, calculating GLOBAL thresholds, then plotting ---
            app.LogTextArea.Value{end+1} = '---';
            app.LogTextArea.Value{end+1} = '--- PASS 2: Aggregating Data, Calculating Global Thresholds, and Saving Plots ---';

            all_log2FC = [];
            valid_sample_indices = find(~cellfun(@(x) isempty(x) || isempty(x.IntensityChange), sampleResults));
            for idx = valid_sample_indices'
                res = sampleResults{idx};
                data_this_run = res.IntensityChange.cell_logFoldChange;
                all_log2FC = [all_log2FC; data_this_run(~isnan(data_this_run))];
            end

            if isempty(all_log2FC)
                uialert(app.NeuroSpikeXRBICUIFigure, 'No valid cell data found across all control samples. Cannot proceed.', 'Fatal Analysis Error');
                return;
            end
            
            % --- CALCULATE GLOBAL THRESHOLDS ---
            alpha = 2.5;
            global_threshold_low = prctile(all_log2FC, alpha);
            global_threshold_high = prctile(all_log2FC, 100 - alpha);
            
            app.LogTextArea.Value{end+1} = sprintf('Global Thresholds Calculated (based on %d cells from %d samples): Low=%.4f, High=%.4f', ...
                numel(all_log2FC), numel(valid_sample_indices), global_threshold_low, global_threshold_high);
            
            % --- SAVE GLOBAL HISTOGRAM (to user-chosen location) ---
            app.LogTextArea.Value{end+1} = '--- Saving Global Plots... ---';
            
            % Prompt user for save location OR use fallback if they cancel saving thresholds
            [file_thresh, path_thresh] = uiputfile('Control_BaselineChange_Thresholds.mat', 'Save Combined Control Thresholds File');
            
            global_output_path = ''; % Initialize variable for global plot save path
            if file_thresh ~= 0
                % If user selected a file and path, use that path for the global histogram
                global_output_path = fullfile(path_thresh, file_thresh);
                % Remove the extension, as we'll add .pdf/.fig later
                global_output_path = fileparts(global_output_path); 
                
                % Save the thresholds
                threshold_high = global_threshold_high;
                threshold_low = global_threshold_low;
                save(fullfile(path_thresh, file_thresh), 'threshold_high', 'threshold_low', 'all_log2FC');
                app.LogTextArea.Value{end+1} = ['Control thresholds saved to: ' fullfile(path_thresh, file_thresh)];
                app.scrollLogToBottom();
            else
                % If user cancels saving thresholds, use the first control sample's folder as fallback
                if ~isempty(app.ControlFolderPaths)
                    parentOutputFolder = app.createOutputFolder(fileparts(app.ControlFolderPaths{1})); 
                else
                    parentOutputFolder = pwd; % Fallback if no control folders were selected
                end
                global_output_path = parentOutputFolder;
                app.LogTextArea.Value{end+1} = 'Thresholds not saved by user. Global histogram will be saved in the first control sample folder.';
            end
            
            % --- Update UI Plots FIRST ---
            % Plot global histogram to UI
            app.plotChangeHistogram(app.ControlsDistributionAxes, all_log2FC, global_threshold_high, global_threshold_low); 
            title(app.ControlsDistributionAxes, 'Global Control Baseline Change Distribution'); % Add a clear label for UI plot
            
            % Plot representative spatial distribution from the first valid sample
            if ~isempty(valid_sample_indices)
                first_valid_idx = valid_sample_indices(1);
                res_first_valid = sampleResults{first_valid_idx};
                if ~isempty(res_first_valid.MatchedCells) && ~isempty(res_first_valid.IntensityChange)
                    app.plotSpatialDistribution(app.ControlSpatialExampleAxes, res_first_valid.MatchedCells, res_first_valid.IntensityChange, global_threshold_high, global_threshold_low);
                    title(app.ControlSpatialExampleAxes, ['Sample Spatial Dist. (Global Thresholds)']);
                end
            end
            
            % --- Save Global Plots ---
            histFig = figure('Visible', 'off');
            ax_hist = axes(histFig); 
            app.plotChangeHistogram(ax_hist, all_log2FC, global_threshold_high, global_threshold_low); 
            baseFigPath_global_hist = fullfile(global_output_path, 'GLOBAL_control_baselineChange_histogram');
            app.saveFigure(histFig, baseFigPath_global_hist);
            close(histFig);
            
            % --- PROCESS AND SAVE INDIVIDUAL SAMPLE RESULTS USING GLOBAL THRESHOLDS ---
            app.LogTextArea.Value{end+1} = '--- Saving Individual Sample Plots (using Global Thresholds) ---';
            for idx = 1:numSamples % Iterate through ALL selected control folders
                res = sampleResults{idx};
                
                % Skip if sample data wasn't processed or IntensityChange is missing
                if isempty(res) || isempty(res.IntensityChange) || isempty(res.MatchedCells)
                    app.LogTextArea.Value{end+1} = ['  Skipping plots for ' res.Name ': No valid data or MatchedCells.'];
                    continue;
                end

                % Get the output folder for this specific sample
                current_sample_folder = app.createOutputFolder(app.ControlFolderPaths{idx});

                app.LogTextArea.Value{end+1} = ['  Saving plots for: ' res.Name ' in ' current_sample_folder ' using global thresholds.'];
                
                % Save IntensityChange for this sample (as before)
                save(fullfile(current_sample_folder, 'IntensityChange.mat'), 'IntensityChange');
                
                % Get the log2FC data for this specific sample
                sample_log2FC = res.IntensityChange.cell_logFoldChange;
                
                % Update the UI histogram to show the individual sample's data
                % but colored/thresholded using the GLOBAL thresholds.
                app.plotChangeHistogram(app.ControlsDistributionAxes, sample_log2FC, global_threshold_high, global_threshold_low); 
                title(app.ControlsDistributionAxes, ['Sample: ' res.Name ' (using Global Thresholds)']);
                app.plotSpatialDistribution(app.ControlSpatialExampleAxes, MatchedCells, IntensityChange, global_threshold_high, global_threshold_low);
                title(app.ControlSpatialExampleAxes, ['Spatial Distribution: ', sampleName]);
                drawnow; 

                % Save individual histogram figure using GLOBAL thresholds
                histFig_ind = figure('Visible', 'off');
                ax_hist_ind = axes(histFig_ind);
                app.plotChangeHistogram(ax_hist_ind, sample_log2FC, global_threshold_high, global_threshold_low); % Use GLOBAL thresholds
                baseFigPath_ind_hist = fullfile(current_sample_folder, 'baselineChange_histogram');
                app.saveFigure(histFig_ind, baseFigPath_ind_hist);
                close(histFig_ind);

                % Save individual spatial distribution plot using GLOBAL thresholds
                spatialFig_ind = figure('Visible', 'off');
                ax_spatial_ind = axes(spatialFig_ind);
                app.plotSpatialDistribution(ax_spatial_ind, res.MatchedCells, res.IntensityChange, global_threshold_high, global_threshold_low);
                baseFigPath_ind_spatial = fullfile(current_sample_folder, 'baselineChange_spatialDistribution');
                app.saveFigure(spatialFig_ind, baseFigPath_ind_spatial);
                close(spatialFig_ind);
            end
            
            % --- FINAL SUMMARY LOGGING ---
            app.plotChangeHistogram(app.ControlsDistributionAxes, all_log2FC, global_threshold_high, global_threshold_low);
            title(app.ControlsDistributionAxes, 'All Control Samples');
            
            % Select first valid control sample for spatial example
            valid_sample = sampleResults{valid_sample_indices(1)};
            app.plotSpatialDistribution(app.ControlSpatialExampleAxes, valid_sample.MatchedCells, valid_sample.IntensityChange, global_threshold_high, global_threshold_low);
            title(app.ControlSpatialExampleAxes, ['Spatial Distribution: ', valid_sample.Name]);

            app.LogTextArea.Value{end+1} = '---';
            app.LogTextArea.Value{end+1} = '--- ANALYSIS COMPLETE ---';
            app.LogTextArea.Value{end+1} = sprintf('Global Thresholds Used (based on %d cells from %d samples): Low=%.4f, High=%.4f', ...
                numel(all_log2FC), numel(valid_sample_indices), global_threshold_low, global_threshold_high);
            
            summaryReport = {'--- Per-Sample Results (using GLOBAL thresholds) ---'};
            for i = 1:numSamples
                res = sampleResults{i};
                if isempty(res) || isempty(res.IntensityChange)
                    summaryReport{end+1,1} = sprintf('  - %s: Skipped or analysis failed.', res.Name);
                    continue;
                end
                % Use the globally calculated thresholds for this summary
                isIncrease = res.IntensityChange.cell_logFoldChange > global_threshold_high;
                isDecrease = res.IntensityChange.cell_logFoldChange < global_threshold_low;
                isNoChange = ~isIncrease & ~isDecrease;
                summaryReport{end+1,1} = sprintf('  - %s: Mean log2(FC)=%.3f, Inc/NoCh/Dec=[%d/%d/%d]', ...
                    res.Name, res.IntensityChange.mean_logFoldChange, sum(isIncrease,'omitnan'), sum(isNoChange,'omitnan'), sum(isDecrease,'omitnan'));
            end
            app.LogTextArea.Value = [app.LogTextArea.Value; summaryReport];
            app.scrollLogToBottom();

            uialert(app.NeuroSpikeXRBICUIFigure, 'Analysis Completed Successfully!', 'Success', 'Icon', 'success');

        end

        % Button pushed function: SelectExperimentalFolderButton
        function SelectExperimentalFolderButtonPushed(app, event)
            folderpath = uigetdir(pwd, '*Select folder of the experimental sample*');
            if folderpath == 0; return; end
            app.ExperimentalFolderPath = folderpath;
            app.ExperimentalFolderField.Value = folderpath;
        end

        % Button pushed function: LoadControlThresholdsButton
        function LoadControlThresholdsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select Control Thresholds File');
    
            % Proceed only if the user did not press "Cancel"
            if file ~= 0
                filepath = fullfile(path, file);
                try
                    data = load(filepath, 'threshold_high', 'threshold_low');
                    if isfield(data, 'threshold_high') && isfield(data, 'threshold_low')
                        app.ThresholdData = data;
                        app.ControlThresholdsField.Value = filepath; % Update the UI to show the path
                        app.LogTextArea.Value = {'Thresholds loaded successfully.'};
                    else
                        uialert(app.NeuroSpikeXRBICUIFigure, 'The selected .mat file does not contain the required threshold variables.', 'File Error');
                    end
                catch ME
                    uialert(app.NeuroSpikeXRBICUIFigure, ['Failed to load file. Error: ' ME.message], 'Load Error');
                end
            end
        end

        % Button pushed function: RunExperimentalButton
        function RunExperimentalButtonPushed(app, event)
            % --- 1. Initial UI Validation & Setup ---
            if isempty(app.ExperimentalFolderPath), uialert(app.NeuroSpikeXRBICUIFigure, 'Please select an experimental folder.', 'Error'); return; end
            subfolderName1 = app.ExpTimepoint1SubfolderNameEditField.Value;
            subfolderName2 = app.ExpTimepoint2SubfolderNameEditField.Value;
            if isempty(subfolderName1) || isempty(subfolderName2), uialert(app.NeuroSpikeXRBICUIFigure, 'Please enter subfolder names.', 'Error'); return; end
            if isempty(app.AnalysisSettings), uialert(app.NeuroSpikeXRBICUIFigure, 'Analysis settings are not initialized.', 'Settings Error'); return; end

            app.LogTextArea.Value = {'Running Experimental Analysis...'};
            app.LogTextArea.Value{end+1} = sprintf('Using settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ...
                app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor, app.AnalysisSettings.RecalculateRadii);
            app.scrollLogToBottom();
            
            folder = app.ExperimentalFolderPath;
            pathParts = strsplit(folder, filesep);
            sampleName = pathParts{end};

            try
                % --- Load or create matched cells data ---
                outputFolder = app.createOutputFolder(folder);
                MatchedCells = [];
                matchedFile = fullfile(outputFolder, 'MatchedCells.mat');
                
                if exist(matchedFile, 'file')
                    app.LogTextArea.Value{end+1} = 'Loading existing MatchedCells.mat...';
                    loaded = load(matchedFile);
                    if isfield(loaded, 'MatchedCells'), MatchedCells = loaded.MatchedCells;
                    else, vars = fieldnames(loaded); if ~isempty(vars), MatchedCells = loaded.(vars{1}); end; end
                else
                    app.LogTextArea.Value{end+1} = 'No MatchedCells.mat found. Please follow prompts for manual cell matching...';
                    drawnow;
                    
                    image1_subfolder = fullfile(filesep, subfolderName1);
                    image2_subfolder = fullfile(filesep, subfolderName2);
                    
                    [MatchedCells, fig_handle_matching] = matchCells(folder, image1_subfolder, image2_subfolder, [], label1, label2);
                    
                    if ishandle(fig_handle_matching)
                        baseFigPath = fullfile(outputFolder, 'diagnostic_cell_matching');
                        app.saveFigure(fig_handle_matching, baseFigPath);
                        close(fig_handle_matching);
                    end
                    
                    app.LogTextArea.Value{end+1} = 'Saving new MatchedCells.mat...';
                    save(matchedFile, 'MatchedCells');
                end
                
                if isempty(MatchedCells), uialert(app.NeuroSpikeXRBICUIFigure, 'Failed to load or create matched cell data.', 'Data Error'); return; end
                
                % --- Perform the main calculation ---
                [IntensityChange, radii1_final, radii2_final] = app.calculateIntensityChange(MatchedCells);

                if isempty(fieldnames(IntensityChange))
                    uialert(app.NeuroSpikeXRBICUIFigure, 'Intensity calculation failed.', 'Calculation Error');
                    return;
                end
                
                % --- Determine Thresholds and Plot Titles ---
                threshold_source_msg = '';
                if ~isempty(app.ThresholdData) % Control thresholds are available
                    th_high = app.ThresholdData.threshold_high;
                    th_low = app.ThresholdData.threshold_low;
                    threshold_source_msg = 'Using loaded control thresholds for stats.';
                    plot_title_hist = 'Experimental Baseline Change Distribution (using Control Thresholds)';
                else % No control thresholds loaded
                    clean_data = IntensityChange.cell_logFoldChange(isfinite(IntensityChange.cell_logFoldChange) & isreal(IntensityChange.cell_logFoldChange));
                    if numel(clean_data) >= 2
                        th_high = prctile(clean_data, 97.5);
                        th_low = prctile(clean_data, 2.5);
                        threshold_source_msg = 'Using this sample''s 2.5/97.5 percentiles for stats.';
                        plot_title_hist = 'Experimental Baseline Change Distribution (Sample Thresholds)';
                    else
                        th_high = NaN; % Cannot determine thresholds
                        th_low = NaN;
                        threshold_source_msg = 'Not enough valid data for sample-specific thresholds.';
                        plot_title_hist = 'Experimental Baseline Change Distribution (Not Enough Data)';
                    end
                end

                % --- FINAL PLOTTING AND SAVING ---
                app.LogTextArea.Value{end+1} = '--- Saving Plots... ---';
                
                % Plot histogram to UI (always using determined thresholds for consistency)
                app.plotChangeHistogram(app.ExperimentalDistributionAxes, IntensityChange.cell_logFoldChange, th_high, th_low);
                title(app.ExperimentalDistributionAxes, plot_title_hist);
                
                % Save histogram plot
                histFig = figure('Visible', 'off');
                ax_hist = axes(histFig); 
                app.plotChangeHistogram(ax_hist, IntensityChange.cell_logFoldChange, th_high, th_low); % Use determined thresholds
                baseFigPath = fullfile(outputFolder, 'baselineChange_histogram');
                app.saveFigure(histFig, baseFigPath);
                close(histFig);
                
                % --- Save spatial plot ONLY if thresholds and data are valid ---
                if ~isnan(th_high) && ~isnan(th_low) && ~isempty(IntensityChange.cell_logFoldChange)
                    spatialFig = figure('Visible', 'off');
                    ax_spatial = axes(spatialFig); 
                    app.plotSpatialDistribution(ax_spatial, MatchedCells, IntensityChange, th_high, th_low); % Use determined thresholds
                    baseFigPath = fullfile(outputFolder, 'baselineChange_spatialDistribution');
                    app.saveFigure(spatialFig, baseFigPath);
                    close(spatialFig);
                else
                    app.LogTextArea.Value{end+1} = 'Skipping spatial distribution plot due to lack of valid thresholds or data.';
                end
                
                % Save excluded cells plot (this one doesn't inherently use thresholds)
                diagFig_exp = figure('Visible', 'off');
                app.createExcludedCellsFigure(diagFig_exp, ...
                    IntensityChange.image1_for_plotting, IntensityChange.centroids1_for_plotting, IntensityChange.invalidMask_for_plotting);
                baseFigPath = fullfile(outputFolder, 'excluded_cells');
                app.saveFigure(diagFig_exp, baseFigPath);
                close(diagFig_exp);
                
                % --- Save IntensityChange.mat ---
                save(fullfile(outputFolder, 'IntensityChange.mat'), 'IntensityChange');
                app.scrollLogToBottom();

                % --- FINAL SUMMARY LOGGING ---
                app.LogTextArea.Value{end+1} = '---';
                app.LogTextArea.Value{end+1} = '--- ANALYSIS COMPLETE ---';
                app.LogTextArea.Value{end+1} = ['Sample: ' sampleName];
                app.LogTextArea.Value{end+1} = ['    - ' threshold_source_msg];
                app.LogTextArea.Value{end+1} = sprintf('    - Mean log2(FC): %.4f', IntensityChange.mean_logFoldChange);
                
                % Recalculate counts for summary based on determined thresholds
                isIncrease = IntensityChange.cell_logFoldChange > th_high;
                isDecrease = IntensityChange.cell_logFoldChange < th_low;
                isNoChange = ~isIncrease & ~isDecrease;
                
                app.LogTextArea.Value{end+1} = sprintf('    - Increase / No Change / Decrease: %d / %d / %d', ...
                    sum(isIncrease,'omitnan'), sum(isNoChange,'omitnan'), sum(isDecrease,'omitnan'));
                app.scrollLogToBottom();
                
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Analysis Error: ' ME.message], 'Error');
                app.LogTextArea.Value{end+1} = getReport(ME, 'extended', 'hyperlinks', 'off');
                app.scrollLogToBottom();
            end

            uialert(app.NeuroSpikeXRBICUIFigure, 'Analysis Completed Successfully!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: FindOptimalSettingsButton
        function FindOptimalSettingsButtonPushed(app, event)
            % --- STAGE 0: UI Validation ---
            if isempty(app.FolderPath1) || isempty(app.FolderPath2)
                uialert(app.NeuroSpikeXRBICUIFigure, 'Please complete Tab 1 first.', 'No Data Loaded');
                return;
            end
            app.LogTextArea.Value = {'--- Starting Optimization ---'};
            
            % --- STAGE 1: LOAD Matched Cell Data ---
            app.LogTextArea.Value{end+1} = 'Step 1: Looking for existing MatchedCells.mat...';
            drawnow;
        
            parentFolder = fileparts(app.FolderPath1);
            outputFolder = fullfile(parentFolder, 'BaselineChange');
            if ~exist(outputFolder, 'dir'), mkdir(outputFolder); end
            matchedFile = fullfile(outputFolder, 'MatchedCells.mat'); 
        
            if ~exist(matchedFile, 'file')
                errorMsg = sprintf(['Optimization requires a "MatchedCells.mat" file.\n\n' ...
                                  'Please run the analysis in the "Experimental" tab for this sample first ' ...
                                  'to perform the manual point selection and create the file.']);
                uialert(app.NeuroSpikeXRBICUIFigure, errorMsg, 'File Not Found');
                app.LogTextArea.Value{end+1} = 'Error: MatchedCells.mat not found. Please run experimental analysis first.';
                return;
            end
            
            try
                app.LogTextArea.Value{end+1} = '    Loading MatchedCells.mat...';
                
                % First, try to load the specific variable 'MatchedCells'
                loadedData = load(matchedFile, 'MatchedCells');
    
                % Now, check if that worked.
                if isfield(loadedData, 'MatchedCells')
                    % The standard case: the file contains the 'MatchedCells' variable.
                    app.TempMatchedData = loadedData.MatchedCells;
                    app.LogTextArea.Value{end+1} = '    -> Found and loaded "MatchedCells" variable.';
                else
                    % The fallback case: 'MatchedCells' wasn't found.
                    app.LogTextArea.Value{end+1} = '    -> "MatchedCells" variable not found. Loading first variable from file as a fallback.';
                    loaded_all = load(matchedFile);
                    vars = fieldnames(loaded_all);
                    
                    if ~isempty(vars)
                        % Assign the data from the first variable found in the file.
                        app.TempMatchedData = loaded_all.(vars{1});
                        app.LogTextArea.Value{end+1} = ['    -> Loaded data from variable named "' vars{1} '".'];
                    else
                        % This is a more serious error - the file is empty.
                        uialert(app.NeuroSpikeXRBICUIFigure, 'The MatchedCells.mat file is empty or corrupted.', 'File Load Error');
                        app.LogTextArea.Value{end+1} = '    -> ERROR: The .mat file is empty.';
                        return;
                    end
                end
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Could not read existing MatchedCells.mat file. Error: ' ME.message], 'File Load Error');
                return;
            end

            app.LogTextArea.Value{end+1} = sprintf('    Using data for %d matched cells.', size(app.TempMatchedData.matchedCentroidCoordinates_0minus, 1));
        
            % --- STAGE 2: Load images for optimization ---
            app.LogTextArea.Value{end+1} = 'Step 2: Loading images from matched data structure...';
            drawnow;
        
            try
                image1_raw = app.TempMatchedData.GCaMP_Image1;
                image2_raw = app.TempMatchedData.GCaMP_Image2;
                centroids1 = app.TempMatchedData.matchedCentroidCoordinates_0minus;
                radii1 = app.TempMatchedData.matchedRadii_0minus;
                centroids2 = app.TempMatchedData.matchedCentroidCoordinates_timepoint2;
                radii2 = app.TempMatchedData.matchedRadii_timepoint2;
        
                se = strel('disk', 15);
                image1_processed = imtophat(im2single(image1_raw), se);
                image2_processed = imtophat(im2single(image2_raw), se);
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Could not load data from matched struct. Error: ' ME.message], 'Load Error');
                return;
            end
        
            % --- STAGE 3: EFFICIENTLY RECALCULATE RADII (IF CHECKED) ---
            radii1_for_optim = radii1; 
            radii2_for_optim = radii2;
            
            if app.RecalculateRadiiCheckBox.Value
                app.LogTextArea.Value{end+1} = 'Step 3: Recalculating all cell radii (this may take time)...';
                d_radii = uiprogressdlg(app.NeuroSpikeXRBICUIFigure, 'Title', 'Recalculating Radii', 'Message', 'Processing...', 'Cancelable', 'on', 'Indeterminate', 'on');
                drawnow;
                
                numCells = size(centroids1, 1);
                recalculated_radii1 = zeros(numCells, 1);
                recalculated_radii2 = zeros(numCells, 1);
                
                try
                    parfor c = 1:numCells
                        r1_orig_cell = radii1(c);
                        patch_half_width1 = ceil(r1_orig_cell * 2.5);
                        ymin1 = max(1, round(centroids1(c,2)) - patch_half_width1);
                        ymax1 = min(size(image1_processed,1), round(centroids1(c,2)) + patch_half_width1);
                        xmin1 = max(1, round(centroids1(c,1)) - patch_half_width1);
                        xmax1 = min(size(image1_processed,2), round(centroids1(c,1)) + patch_half_width1);
                        patch1 = image1_processed(ymin1:ymax1, xmin1:xmax1);
                        if ~isempty(patch1)
                            recalculated_radii1(c) = findBaselineRadius(patch1, r1_orig_cell);
                        else
                            recalculated_radii1(c) = r1_orig_cell;
                        end
        
                        r2_orig_cell = radii2(c);
                        patch_half_width2 = ceil(r2_orig_cell * 2.5);
                        ymin2 = max(1, round(centroids2(c,2)) - patch_half_width2);
                        ymax2 = min(size(image2_processed,1), round(centroids2(c,2)) + patch_half_width2);
                        xmin2 = max(1, round(centroids2(c,1)) - patch_half_width2);
                        xmax2 = min(size(image2_processed,2), round(centroids2(c,1)) + patch_half_width2);
                        patch2 = image2_processed(ymin2:ymax2, xmin2:xmax2);
                        if ~isempty(patch2)
                            recalculated_radii2(c) = findBaselineRadius(patch2, r2_orig_cell);
                        else
                            recalculated_radii2(c) = r2_orig_cell;
                        end
                    end
                    
                    if d_radii.CancelRequested, close(d_radii); app.LogTextArea.Value{end+1} = 'Radius recalculation canceled.'; return; end
                    
                    app.LogTextArea.Value{end+1} = '    -> Generating diagnostic radii plot...';
                    drawnow;
                    
                    diagFig = figure('Name', 'Radii Recalculation Diagnostic', 'NumberTitle', 'off', 'Visible', 'off');
                    t = tiledlayout(1, 2, 'Padding', 'compact', 'TileSpacing', 'compact');
                    title(t, 'Original (Red) vs. Recalculated (Green) Radii');
        
                    ax1 = nexttile;
                    imshow(image1_raw, 'Parent', ax1);
                    hold(ax1, 'on');
                    viscircles(ax1, centroids1, radii1, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', '--');
                    viscircles(ax1, centroids1, recalculated_radii1, 'Color', 'g', 'LineWidth', 1);
                    title(ax1, 'Timepoint 1');
                    hold(ax1, 'off');
        
                    ax2 = nexttile;
                    imshow(image2_raw, 'Parent', ax2);
                    hold(ax2, 'on');
                    viscircles(ax2, centroids2, radii2, 'Color', 'r', 'LineWidth', 0.5, 'LineStyle', '--');
                    viscircles(ax2, centroids2, recalculated_radii2, 'Color', 'g', 'LineWidth', 1);
                    title(ax2, 'Timepoint 2');
                    hold(ax2, 'off');
                    
                    baseFigPath = fullfile(outputFolder, 'diagnostic_recalculated_radii_OPTIMIZATION');
                    app.saveFigure(diagFig, baseFigPath);
                    close(diagFig);
                    app.LogTextArea.Value{end+1} = ['    -> Diagnostic plots saved to folder: ' outputFolder];

                    radii1_for_optim = recalculated_radii1;
                    radii2_for_optim = recalculated_radii2;
                    app.LogTextArea.Value{end+1} = '    -> Radius recalculation complete.';
                    
                catch ME
                    close(d_radii);
                    uialert(app.NeuroSpikeXRBICUIFigure, ['Error during radius recalculation: ' ME.message], 'Error');
                    return;
                end
                close(d_radii);
            else
                app.LogTextArea.Value{end+1} = 'Step 3: Skipping radius recalculation.';
            end
        
            % --- STAGE 4: RUN MAIN OPTIMIZATION ---
            app.FindOptimalSettingsButton.Enable = 'off';
            app.ApplyandSaveButton.Enable = 'off';
            app.VisualizeFailedCellsButton.Enable = 'off';
            app.LogTextArea.Value{end+1} = 'Step 4: Optimizing background settings...';
            
            d_optim = uiprogressdlg(app.NeuroSpikeXRBICUIFigure, 'Title', 'Optimizing Background', 'Message', 'Running MEDIAN tests...', 'Indeterminate', 'on', 'Cancelable', 'on');
            
            try
                median_results = app.optimizeBackgroundSettings(...
                    image1_processed, image2_processed, ...
                    centroids1, radii1_for_optim, ...
                    centroids2, radii2_for_optim, ...
                    'Median');
                
                if d_optim.CancelRequested, close(d_optim); app.FindOptimalSettingsButton.Enable = 'on'; return; end
                d_optim.Message = 'Running MEAN tests...';
                
                mean_results = app.optimizeBackgroundSettings(...
                    image1_processed, image2_processed, ...
                    centroids1, radii1_for_optim, ...
                    centroids2, radii2_for_optim, ...
                    'Mean');
                    
                if d_optim.CancelRequested, close(d_optim); app.FindOptimalSettingsButton.Enable = 'on'; return; end
            
            catch ME
                close(d_optim);
                app.FindOptimalSettingsButton.Enable = 'on';
                uialert(app.NeuroSpikeXRBICUIFigure, ['Optimization Error: ' ME.message], 'Error');
                return;
            end
            close(d_optim);
        
            % --- STAGE 5: PROCESS AND DISPLAY RESULTS ---
            if ~isempty(median_results) && ~isempty(mean_results)
                full_results = sortrows([median_results; mean_results], 'QualityScore_percent', 'descend');
                app.OptimizationResults = full_results;
                app.SettingsResultsTable.Data = full_results;
                app.SettingsResultsTable.ColumnName = {'Inner Factor', 'Outer Factor', 'Method', 'Quality Score (%)'};
                addStyle(app.SettingsResultsTable, uistyle('BackgroundColor', '#90EE90'), 'row', 1);
                winner = app.OptimizationResults(1,:);
                app.LogTextArea.Value{end+1} = sprintf('Optimization Complete. Best method: %s (%.2f%% score)', winner.Method{:}, winner.QualityScore_percent);
                app.ApplyandSaveButton.Enable = 'on';
                app.VisualizeFailedCellsButton.Enable = 'on';
            else
                app.LogTextArea.Value{end+1} = 'Optimization did not return results.';
            end
            app.FindOptimalSettingsButton.Enable = 'on';
        end

        % Button pushed function: ApplyandSaveButton
        function ApplyandSaveButtonPushed(app, event)
            if isempty(app.OptimizationResults)
                uialert(app.NeuroSpikeXRBICUIFigure, 'No optimization results to apply.', 'Error');
                return;
            end
            bestSettings = app.OptimizationResults(1, :);
            
            % Check if the AnalysisSettings struct has not been initialized yet.
            if isempty(app.AnalysisSettings)
                % If it's empty, initialize it as a scalar struct before adding fields.
                app.AnalysisSettings = struct();
                app.LogTextArea.Value{end+1} = 'Note: Initializing settings struct before applying optimal settings.';
            end
        
            % Now we are GUARANTEED that app.AnalysisSettings is a valid struct,
            % so dot notation assignment will work.
            
            % Update the app's internal settings property
            app.AnalysisSettings.Method = bestSettings.Method{:};
            app.AnalysisSettings.InnerFactor = bestSettings.InnerFactor;
            app.AnalysisSettings.OuterFactor = bestSettings.OuterFactor;
            app.AnalysisSettings.RecalculateRadii = app.RecalculateRadiiCheckBox.Value;
            
            % Also update the manual UI controls to reflect the new optimal settings
            app.ManualMethodDropDown.Value = app.AnalysisSettings.Method;
            app.ManualInnerEditField.Value = app.AnalysisSettings.InnerFactor;
            app.ManualOuterEditField.Value = app.AnalysisSettings.OuterFactor;
            
            % Save the new optimal settings
            appPath = app.getAppPath();
            settingsFile = fullfile(appPath, 'CalciumBaselineAnalysisSettings.mat');
            savedSettings = app.AnalysisSettings; % The variable to save is the whole struct
            try
                save(settingsFile, 'savedSettings'); % Save the variable named 'savedSettings'
                uialert(app.NeuroSpikeXRBICUIFigure, sprintf('Optimal settings (Method: %s, Inner: %.2f, Outer: %.2f) have been applied and saved.', ...
                    app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor), 'Settings Applied','Icon','success');
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Save Error: ' ME.message], 'Save Error');
            end
        end

        % Button pushed function: VisualizeFailedCellsButton
        function VisualizeFailedCellsButtonPushed(app, event)
            % Check that an optimization has been run and we have results
            if isempty(app.OptimizationResults)
                uialert(app.NeuroSpikeXRBICUIFigure, 'Please run the optimization first.', 'No Results');
                return;
            end
            
            app.LogTextArea.Value{end+1} = 'Generating diagnostic plot for failed cells...';
            drawnow;
            
            try
                % --- Check if the temporary matched data exists ---
                if isempty(app.TempMatchedData)
                    uialert(app.NeuroSpikeXRBICUIFigure, 'No matched data found. Please run the optimization first.', 'Error');
                    return;
                end
            
                % --- Use the data from the TempMatchedData struct ---
                image1_raw = app.TempMatchedData.GCaMP_Image1;
                centroids1 = app.TempMatchedData.matchedCentroidCoordinates_0minus;
                radii1 = app.TempMatchedData.matchedRadii_0minus;
                
                % --- Get the BEST settings from the previous optimization ---
                bestSettings = app.OptimizationResults(1, :);
                bgMethod = bestSettings.Method{:};
                bgInnerFactor = bestSettings.InnerFactor;
                bgOuterFactor = bestSettings.OuterFactor;
                
                % --- Apply Top-Hat filtering (same as in the optimization) ---
                se = strel('disk', 15);
                image1 = imtophat(image1_raw, se);
                
                % --- Re-calculate intensities for all cells using ONLY the best settings ---
                numCells = size(centroids1, 1);
                intensities = zeros(numCells, 1);
                
                for c = 1:numCells
                    % Call the single, authoritative function
                    [intensities(c), ~] = calculateSingleCellIntensity(...
                        image1, ...
                        centroids1(c,1), centroids1(c,2), radii1(c), ...
                        bgInnerFactor, ...
                        bgOuterFactor, ...
                        bgMethod);
                end
                
                % --- Identify good and bad cells ---
                isGood = intensities > 0;
                isBad = ~isGood;
                
                % --- Create a NEW figure for the diagnostic plot ---
                diagFig = figure('Name', 'Diagnostic Plot: ROI Quality', 'NumberTitle', 'off');
                imshow(image1_raw); % Show the original image for clarity
                hold on;
                
                % Plot good cells in green
                viscircles(centroids1(isGood, :), radii1(isGood), 'Color', 'g', 'LineWidth', 0.5);
                
                % Plot bad cells in red
                viscircles(centroids1(isBad, :), radii1(isBad), 'Color', 'r', 'LineWidth', 1);
                
                title(sprintf('ROI Quality Check: %d Good Cells (Green), %d Failed Cells (Red)', sum(isGood), sum(isBad)));
                hold off;
                
                outputFolder = app.createOutputFolder(fileparts(app.FolderPath1));
                baseFigPath = fullfile(outputFolder, 'diagnostic_ROI_quality');

                app.saveFigure(diagFig, baseFigPath);
                % We don't close the figure here, so the user can visualize it.

                app.LogTextArea.Value{end+1} = 'Diagnostic plot created and saved.';
                
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Could not generate diagnostic plot. Error: ' ME.message], 'Diagnostic Error');
                disp(ME.getReport);
            end
        end

        % Button pushed function: ApplyManualButton
        function ApplyManualButtonPushed(app, event)
            % Check if the AnalysisSettings struct has not been initialized yet.
            if isempty(app.AnalysisSettings)
                % If it's empty, initialize it as a scalar struct before trying to add fields.
                app.AnalysisSettings = struct(); 
                app.LogTextArea.Value{end+1} = 'Note: Initializing settings struct.';
            end
            
            % Now, we are GUARANTEED that app.AnalysisSettings is a valid struct,
            % so dot notation assignment will work.
            
            % Read values from the manual input UI controls
            app.AnalysisSettings.Method = app.ManualMethodDropDown.Value;
            app.AnalysisSettings.InnerFactor = app.ManualInnerEditField.Value;
            app.AnalysisSettings.OuterFactor = app.ManualOuterEditField.Value;
            app.AnalysisSettings.RecalculateRadii = app.RecalculateRadiiCheckBox.Value;
            
            % Save these manually entered settings to the file
            appPath = app.getAppPath();
            settingsFile = fullfile(appPath, 'CalciumBaselineAnalysisSettings.mat');
            savedSettings = app.AnalysisSettings;
            try
                save(settingsFile, 'savedSettings');
                uialert(app.NeuroSpikeXRBICUIFigure, sprintf('Manual settings (Method: %s, Inner: %.2f, Outer: %.2f) have been applied and saved.', ...
                    app.AnalysisSettings.Method, app.AnalysisSettings.InnerFactor, app.AnalysisSettings.OuterFactor), 'Settings Saved','Icon','success');
                app.LogTextArea.Value{end+1} = 'Manual settings saved.';
            catch ME
                uialert(app.NeuroSpikeXRBICUIFigure, ['Could not save settings. Error: ' ME.message], 'Save Error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Check if settings are missing (e.g., if app startup somehow failed)
            if isempty(app.AnalysisSettings)
                app.LogTextArea.Value{end+1} = 'Settings were not loaded. Attempting to load now...';
                
                % Step 1: Initialize with a complete default struct as a fallback
                app.AnalysisSettings = struct(...
                    'Method', 'Median', ...
                    'InnerFactor', 1.8, ...
                    'OuterFactor', 2.0, ...
                    'RecalculateRadii', false); % Ensure default is complete

                % Step 2: Attempt to load from the saved file
                settingsFile = fullfile(app.getAppPath(), 'CalciumBaselineAnalysisSettings.mat');
                if exist(settingsFile, 'file')
                    try
                        loadedVars = load(settingsFile, 'savedSettings');
                        if isfield(loadedVars, 'savedSettings') && isstruct(loadedVars.savedSettings)
                            sourceStruct = loadedVars.savedSettings;
                            
                            % --- THE FIX IS HERE: Validate and apply ALL fields ---
                            if isfield(sourceStruct, 'Method')
                                app.AnalysisSettings.Method = sourceStruct.Method;
                            end
                            if isfield(sourceStruct, 'InnerFactor')
                                app.AnalysisSettings.InnerFactor = sourceStruct.InnerFactor;
                            end
                            if isfield(sourceStruct, 'OuterFactor')
                                app.AnalysisSettings.OuterFactor = sourceStruct.OuterFactor;
                            end
                            if isfield(sourceStruct, 'RecalculateRadii')
                                app.AnalysisSettings.RecalculateRadii = sourceStruct.RecalculateRadii;
                            end
                            % --- END OF FIX ---
                            
                            app.LogTextArea.Value{end+1} = 'Successfully re-loaded settings from file.';
                        else
                           app.LogTextArea.Value{end+1} = 'Warning: Settings file found but is malformed. Using defaults.';
                        end
                    catch ME
                        app.LogTextArea.Value{end+1} = ['Error loading settings file: ' ME.message '. Using defaults.'];
                    end
                else
                    app.LogTextArea.Value{end+1} = 'No settings file found. Using defaults.';
                end
                 app.LogTextArea.Value{end+1} = sprintf(...
                    'Current settings: Method=%s, Inner=%.2f, Outer=%.2f, RecalcRadii=%d', ...
                    app.AnalysisSettings.Method, ...
                    app.AnalysisSettings.InnerFactor, ...
                    app.AnalysisSettings.OuterFactor, ...
                    app.AnalysisSettings.RecalculateRadii);
            end

            % --- Original Tab Switching UI Logic ---
            selectedTab = app.TabGroup.SelectedTab;
    
            if selectedTab == app.AnalysisPipelineTab
                callbackToExecute = app.AnalysisModeGroup.SelectionChangedFcn;
                event_data.Source = app.AnalysisModeGroup;
                callbackToExecute(app.AnalysisModeGroup, event_data);
            
            elseif selectedTab == app.AnalysisSettingsoptionalTab
                try
                    % Update the UI controls on Tab 2 to reflect the current settings
                    app.ManualMethodDropDown.Value = app.AnalysisSettings.Method;
                    app.ManualInnerEditField.Value = app.AnalysisSettings.InnerFactor;
                    app.ManualOuterEditField.Value = app.AnalysisSettings.OuterFactor;
                    app.RecalculateRadiiCheckBox.Value = app.AnalysisSettings.RecalculateRadii;
                catch ME
                    app.LogTextArea.Value{end+1} = ['Error updating Tab 2 UI: ' ME.message];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroSpikeXRBICUIFigure = uifigure('Visible', 'off');
            app.NeuroSpikeXRBICUIFigure.Name = 'NeuroSpikeX RBIC';
            app.TabGroup = uitabgroup(app.NeuroSpikeXRBICUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FrameSelectionTab = uitab(app.TabGroup);
            app.FrameSelectionTab.Title = '1. Frame Selection';
            app.FrameSelectionGrid = uigridlayout(app.FrameSelectionTab);
            app.Timepoint2Axes = uiaxes(app.FrameSelectionGrid);
            title(app.Timepoint2Axes, 'Time Point 2')
            xlabel(app.Timepoint2Axes, 'Time (s)')
            ylabel(app.Timepoint2Axes, 'dF/F')
            app.Timepoint1Axes = uiaxes(app.FrameSelectionGrid);
            title(app.Timepoint1Axes, 'Time Point 1')
            xlabel(app.Timepoint1Axes, 'Time (s)')
            ylabel(app.Timepoint1Axes, 'dF/F')
            app.SelectAndLoadButton = uibutton(app.FrameSelectionGrid, 'push');
            app.SelectAndLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAndLoadButtonPushed, true);
            app.SelectAndLoadButton.Text = 'Select Timepoint Folders & Load Signals';
            app.Timepoint1EditFieldLabel = uilabel(app.FrameSelectionGrid);
            app.Timepoint1EditFieldLabel.Text = 'Timepoint 1:';
            app.Timepoint1EditField = uieditfield(app.FrameSelectionGrid, 'text');
            app.Timepoint1EditField.Editable = 'off';
            app.Timepoint2EditFieldLabel = uilabel(app.FrameSelectionGrid);
            app.Timepoint2EditFieldLabel.Text = 'Timepoint 2:';
            app.Timepoint2EditField = uieditfield(app.FrameSelectionGrid, 'text');
            app.InstructionsLabel = uilabel(app.FrameSelectionGrid);
            app.InstructionsLabel.Text = 'Instructions';
            app.AnalysisSettingsoptionalTab = uitab(app.TabGroup);
            app.AnalysisSettingsoptionalTab.Title = '2. Analysis Settings (optional)';
            app.GridLayout = uigridlayout(app.AnalysisSettingsoptionalTab);
            app.FindOptimalSettingsButton = uibutton(app.GridLayout, 'push');
            app.FindOptimalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FindOptimalSettingsButtonPushed, true);
            app.FindOptimalSettingsButton.Text = 'Find Optimal Background Settings';
            app.SettingsInstructionsLabel = uilabel(app.GridLayout);
            app.SettingsInstructionsLabel.Text = {'1. Complete Tab 1 to load a dataset.'; '2. Click "Find Optimal Settings" to run the optimization.'; '3. The best combination of settings will be highlighted in the table below.'};
            app.ApplyandSaveButton = uibutton(app.GridLayout, 'push');
            app.ApplyandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyandSaveButtonPushed, true);
            app.ApplyandSaveButton.Enable = 'off';
            app.ApplyandSaveButton.Text = 'Apply & Save Optimal Settings';
            app.SettingsResultsTable = uitable(app.GridLayout);
            app.SettingsResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SettingsResultsTable.RowName = {};
            app.VisualizeFailedCellsButton = uibutton(app.GridLayout, 'push');
            app.VisualizeFailedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFailedCellsButtonPushed, true);
            app.VisualizeFailedCellsButton.Enable = 'off';
            app.VisualizeFailedCellsButton.Text = 'Visualize Failed Cells';
            app.ORLabel = uilabel(app.GridLayout);
            app.ORLabel.Text = '---------------------------------------------------------------------- OR ----------------------------------------------------------------------';
            app.ManualBackgroundSubtractionMethodLabel = uilabel(app.GridLayout);
            app.ManualBackgroundSubtractionMethodLabel.Text = 'Manual Background Subtraction Method:';
            app.ManualMethodDropDown = uidropdown(app.GridLayout);
            app.ManualMethodDropDown.Items = {'Median', 'Mean'};
            app.ManualMethodDropDown.Value = 'Median';
            app.ManualOuterFactorLabel = uilabel(app.GridLayout);
            app.ManualOuterFactorLabel.Text = 'Manual Outer Factor:';
            app.ManualOuterEditField = uieditfield(app.GridLayout, 'numeric');
            app.ManualOuterEditField.Value = 2;
            app.InnerRadiusLabel = uilabel(app.GridLayout);
            app.InnerRadiusLabel.Text = 'Manual Inner Factor:';
            app.ManualInnerEditField = uieditfield(app.GridLayout, 'numeric');
            app.ManualInnerEditField.Value = 1.8;
            app.ApplyManualButton = uibutton(app.GridLayout, 'push');
            app.ApplyManualButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyManualButtonPushed, true);
            app.ApplyManualButton.Text = 'Apply & Save Manual Settings';
            app.RecalculateRadiiCheckBox = uicheckbox(app.GridLayout);
            app.RecalculateRadiiCheckBox.Text = 'Dynamically recalculate cell radii at baseline fluorescence (slower)';
            app.RecalculateRadiiCheckBox.Value = true;
            app.AnalysisPipelineTab = uitab(app.TabGroup);
            app.AnalysisPipelineTab.Title = '3. Analysis Pipeline';
            app.AnalysisGrid = uigridlayout(app.AnalysisPipelineTab);
            app.ControlAnalysisPanel = uipanel(app.AnalysisGrid);
            app.ControlAnalysisPanel.Title = 'Control Analysis';
            app.ControlAnalysisGrid = uigridlayout(app.ControlAnalysisPanel);
            app.ControlSpatialExampleAxes = uiaxes(app.ControlAnalysisGrid);
            title(app.ControlSpatialExampleAxes, 'Title')
            xlabel(app.ControlSpatialExampleAxes, 'X')
            ylabel(app.ControlSpatialExampleAxes, 'Y')
            zlabel(app.ControlSpatialExampleAxes, 'Z')
            app.ControlsDistributionAxes = uiaxes(app.ControlAnalysisGrid);
            title(app.ControlsDistributionAxes, 'Title')
            xlabel(app.ControlsDistributionAxes, 'X')
            ylabel(app.ControlsDistributionAxes, 'Y')
            zlabel(app.ControlsDistributionAxes, 'Z')
            app.SelectControlFoldersButton = uibutton(app.ControlAnalysisGrid, 'push');
            app.SelectControlFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectControlFoldersButtonPushed, true);
            app.SelectControlFoldersButton.Text = 'Select Control Folders';
            app.ControlsListBoxLabel = uilabel(app.ControlAnalysisGrid);
            app.ControlsListBoxLabel.Text = 'Controls:';
            app.ControlsListBox = uilistbox(app.ControlAnalysisGrid);
            app.RunControlButton = uibutton(app.ControlAnalysisGrid, 'push');
            app.RunControlButton.ButtonPushedFcn = createCallbackFcn(app, @RunControlButtonPushed, true);
            app.RunControlButton.Text = 'RUN ANALYSIS';
            app.Timepoint2SubfolderNameEditFieldLabel = uilabel(app.ControlAnalysisGrid);
            app.Timepoint2SubfolderNameEditFieldLabel.Text = 'Timepoint 2 Subfolder Name:';
            app.Timepoint2SubfolderNameEditField = uieditfield(app.ControlAnalysisGrid, 'text');
            app.Timepoint2SubfolderNameEditField.Value = '0plus';
            app.Timepoint1SubfolderNameEditFieldLabel = uilabel(app.ControlAnalysisGrid);
            app.Timepoint1SubfolderNameEditFieldLabel.Text = 'Timepoint 1 Subfolder Name:';
            app.Timepoint1SubfolderNameEditField = uieditfield(app.ControlAnalysisGrid, 'text');
            app.Timepoint1SubfolderNameEditField.Value = '0minus';
            app.ExperimentalAnalysisPanel = uipanel(app.AnalysisGrid);
            app.ExperimentalAnalysisPanel.Title = 'Experimental Analysis';
            app.ExperimentalAnalysisGrid = uigridlayout(app.ExperimentalAnalysisPanel);
            app.ExperimentalSpatialAxes = uiaxes(app.ExperimentalAnalysisGrid);
            title(app.ExperimentalSpatialAxes, 'Title')
            xlabel(app.ExperimentalSpatialAxes, 'X')
            ylabel(app.ExperimentalSpatialAxes, 'Y')
            zlabel(app.ExperimentalSpatialAxes, 'Z')
            app.ExperimentalDistributionAxes = uiaxes(app.ExperimentalAnalysisGrid);
            title(app.ExperimentalDistributionAxes, 'Title')
            xlabel(app.ExperimentalDistributionAxes, 'X')
            ylabel(app.ExperimentalDistributionAxes, 'Y')
            zlabel(app.ExperimentalDistributionAxes, 'Z')
            app.SelectExperimentalFolderButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.SelectExperimentalFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentalFolderButtonPushed, true);
            app.SelectExperimentalFolderButton.Text = 'Select Experimental Folder';
            app.LoadControlThresholdsButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.LoadControlThresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlThresholdsButtonPushed, true);
            app.LoadControlThresholdsButton.Text = 'Load Control Thresholds';
            app.RunExperimentalButton = uibutton(app.ExperimentalAnalysisGrid, 'push');
            app.RunExperimentalButton.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentalButtonPushed, true);
            app.RunExperimentalButton.Text = 'RUN ANALYSIS';
            app.ExperimentalFolderField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExperimentalFolderField.Editable = 'off';
            app.ControlThresholdsField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ControlThresholdsField.Editable = 'off';
            app.Timepoint1SubfolderNameEditField_2Label = uilabel(app.ExperimentalAnalysisGrid);
            app.Timepoint1SubfolderNameEditField_2Label.Text = 'Timepoint 1 Subfolder Name:';
            app.ExpTimepoint1SubfolderNameEditField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExpTimepoint1SubfolderNameEditField.Value = '0minus';
            app.Timepoint2SubfolderNameEditField_2Label = uilabel(app.ExperimentalAnalysisGrid);
            app.Timepoint2SubfolderNameEditField_2Label.Text = 'Timepoint 2 Subfolder Name:';
            app.ExpTimepoint2SubfolderNameEditField = uieditfield(app.ExperimentalAnalysisGrid, 'text');
            app.ExpTimepoint2SubfolderNameEditField.Value = '0plus';
            app.AnalysisModeGroup = uibuttongroup(app.AnalysisGrid);
            app.AnalysisModeGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisModeGroupSelectionChanged, true);
            app.ControlModeButton = uiradiobutton(app.AnalysisModeGroup);
            app.ControlModeButton.Text = 'Control Sample Mode';
            app.ExperimentalModeButton = uiradiobutton(app.AnalysisModeGroup);
            app.ExperimentalModeButton.Text = 'Experimental Sample Mode';
            app.ExperimentalModeButton.Value = true;
            app.LogTextArea = uitextarea(app.NeuroSpikeXRBICUIFigure);
            % Show the figure after all components are created
            app.NeuroSpikeXRBICUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RelativeBaselineChangeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroSpikeXRBICUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroSpikeXRBICUIFigure)
        end
    end
end